classdef basicTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasicTrainingAppUIFigure     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GeneralSettingsTab           matlab.ui.container.Tab
        OptionsPanel                 matlab.ui.container.Panel
        GeneralGrid                  matlab.ui.container.GridLayout
        StartCheckBox                matlab.ui.control.CheckBox
        DuringCheckBox               matlab.ui.control.CheckBox
        EndCheckBox                  matlab.ui.control.CheckBox
        SPButton                     matlab.ui.control.Button
        InfoEditFieldLabel           matlab.ui.control.Label
        InfoEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        SubjectEditField             matlab.ui.control.EditField
        SavePathEditFieldLabel       matlab.ui.control.Label
        SavePathEditField            matlab.ui.control.EditField
        StimulusDropDownLabel        matlab.ui.control.Label
        StimulusDropDown             matlab.ui.control.DropDown
        SP2Button                    matlab.ui.control.Button
        ImagesEditFieldLabel         matlab.ui.control.Label
        ImagesEditField              matlab.ui.control.EditField
        RandomPositionCheckBox       matlab.ui.control.CheckBox
        PositionsEditFieldLabel      matlab.ui.control.Label
        PositionsEditField           matlab.ui.control.EditField
        LabJackTriggerCheckBox       matlab.ui.control.CheckBox
        GOButton                     matlab.ui.control.Button
        DebugModeCheckBox            matlab.ui.control.CheckBox
        PlaytimesEditFieldLabel      matlab.ui.control.Label
        PlaytimesEditField           matlab.ui.control.NumericEditField
        DistancecmEditFieldLabel     matlab.ui.control.Label
        DistancecmEditField          matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel    matlab.ui.control.Label
        PixelsPerCmEditField         matlab.ui.control.NumericEditField
        ITIsEditFieldLabel           matlab.ui.control.Label
        ITIsEditField                matlab.ui.control.NumericEditField
        TimeOutsEditFieldLabel       matlab.ui.control.Label
        TimeOutsEditField            matlab.ui.control.NumericEditField
        BlocksEditFieldLabel         matlab.ui.control.Label
        BlocksEditField              matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel     matlab.ui.control.Label
        BackgroundEditField          matlab.ui.control.EditField
        PhotodiodeCheckBox           matlab.ui.control.CheckBox
        calPathButton                matlab.ui.control.Button
        calPathEditFieldLabel        matlab.ui.control.Label
        calPathEditField             matlab.ui.control.EditField
        UseArduinoCheckBox           matlab.ui.control.CheckBox
        expTimeLabel                 matlab.ui.control.Label
        lJTypeDropDown               matlab.ui.control.DropDown
        InitiateFixationCheckBox     matlab.ui.control.CheckBox
        VisualFeedbackCheckBox       matlab.ui.control.CheckBox
        EyetrackersettingsPanel      matlab.ui.container.Panel
        StrictEditFieldLabel         matlab.ui.control.Label
        StrictEditField              matlab.ui.control.NumericEditField
        INITEditFieldLabel           matlab.ui.control.Label
        INITEditField                matlab.ui.control.NumericEditField
        FIXEditFieldLabel            matlab.ui.control.Label
        FIXEditField                 matlab.ui.control.NumericEditField
        TrackerDropDownLabel         matlab.ui.control.Label
        TrackerDropDown              matlab.ui.control.DropDown
        TrackingmodeDropDownLabel    matlab.ui.control.Label
        TrackingmodeDropDown         matlab.ui.control.DropDown
        CalEditFieldLabel            matlab.ui.control.Label
        CalEditField                 matlab.ui.control.EditField
        CalibrationstimulusDropDownLabel  matlab.ui.control.Label
        CalibrationstimulusDropDown  matlab.ui.control.DropDown
        ValEditFieldLabel            matlab.ui.control.Label
        ValEditField                 matlab.ui.control.EditField
        AutoPaceCheckBox             matlab.ui.control.CheckBox
        UseEyeTrackerCheckBox        matlab.ui.control.CheckBox
        DummyModeCheckBox            matlab.ui.control.CheckBox
        eyeCALButton                 matlab.ui.control.Button
        CalibrationEditFieldLabel    matlab.ui.control.Label
        CalibrationEditField         matlab.ui.control.EditField
        PaceDurationEditField        matlab.ui.control.NumericEditField
        SpotSizeEditFieldLabel       matlab.ui.control.Label
        SpotSizeEditField            matlab.ui.control.NumericEditField
        SamplerateKnobLabel          matlab.ui.control.Label
        SamplerateKnob               matlab.ui.control.DiscreteKnob
        ReloadPreviousCheckBox       matlab.ui.control.CheckBox
        EyeusedDropDownLabel         matlab.ui.control.Label
        EyeusedDropDown              matlab.ui.control.DropDown
        EyeDropDownLabel             matlab.ui.control.Label
        smoothEyeDropDown            matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        smoothMethodDropDown         matlab.ui.control.DropDown
        wEditFieldLabel              matlab.ui.control.Label
        windowEditField              matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        nSamplesEditField            matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        ManualCheckBox               matlab.ui.control.CheckBox
        SpotAlphaEditFieldLabel      matlab.ui.control.Label
        SpotAlphaEditField           matlab.ui.control.NumericEditField
        SpotLineEditFieldLabel       matlab.ui.control.Label
        SpotLineEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        GraceTimeEditFieldLabel      matlab.ui.control.Label
        GraceTimeEditField           matlab.ui.control.NumericEditField
        RADIUSEditFieldLabel         matlab.ui.control.Label
        RADIUSEditField              matlab.ui.control.EditField
        DrawEyeDropDownLabel         matlab.ui.control.Label
        DrawEyeDropDown              matlab.ui.control.DropDown
        VEPSettingsTab               matlab.ui.container.Tab
        VEPStimuliPanel              matlab.ui.container.Panel
        VEPGrid                      matlab.ui.container.GridLayout
        VEPSFEditField               matlab.ui.control.EditField
        VEPSFEditFieldLabel          matlab.ui.control.Label
        VEPContrastEditFieldLabel    matlab.ui.control.Label
        VEPContrastEditField         matlab.ui.control.EditField
        LogCheckBox                  matlab.ui.control.CheckBox
        VEPFlickerEditField          matlab.ui.control.EditField
        VEPFlickerEditFieldLabel     matlab.ui.control.Label
        TypeDropDownLabel            matlab.ui.control.Label
        TypeDropDown                 matlab.ui.control.DropDown
        LogCheckBox2                 matlab.ui.control.CheckBox
        sfPeakEditFieldLabel         matlab.ui.control.Label
        sfPeakEditField              matlab.ui.control.NumericEditField
        sfSigmaEditFieldLabel        matlab.ui.control.Label
        sfSigmaEditField             matlab.ui.control.NumericEditField
        aPeakEditFieldLabel          matlab.ui.control.Label
        aPeakEditField               matlab.ui.control.NumericEditField
        aSigmaEditFieldLabel         matlab.ui.control.Label
        aSigmaEditField              matlab.ui.control.NumericEditField
        SizedegEditFieldLabel        matlab.ui.control.Label
        SizedegEditField             matlab.ui.control.NumericEditField
        AddMaskCheckBox              matlab.ui.control.CheckBox
        ExpTimeTextArea              matlab.ui.control.TextArea
        AddBlankCheckBox             matlab.ui.control.CheckBox
        PlotsTab                     matlab.ui.container.Tab
        ResultsPanel                 matlab.ui.container.Panel
        PlotAxis1                    matlab.ui.control.UIAxes
        PlotAxis2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ana % UI state passed to matlab
    end
    
    methods (Access = private)
        
		 function parseSettings(app)
			 app.ana = [];
			 app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
			 app.ana.subject = app.SubjectEditField.Value;
			 app.ana.comments = app.InfoEditField.Value;
			 app.ana.ResultDir = app.SavePathEditField.Value;
			 app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
			 app.ana.distance = app.DistancecmEditField.Value;
			 
			 app.ana.playTimes = app.PlaytimesEditField.Value;
			 app.ana.ITI = app.ITIsEditField.Value;
			 app.ana.timeOut = app.TimeOutsEditField.Value;
			 app.ana.nBlocks = app.BlocksEditField.Value;
			 app.ana.addBlank = app.AddBlankCheckBox.Value;
			 
			 app.ana.rewardStart = logical(app.StartCheckBox.Value);
			 app.ana.rewardDuring = logical(app.DuringCheckBox.Value);
			 app.ana.rewardEnd = logical(app.EndCheckBox.Value);
			 app.ana.useArduino = logical(app.UseArduinoCheckBox.Value);
			 app.ana.sendTrigger = logical(app.LabJackTriggerCheckBox.Value);
			 app.ana.lJType = app.lJTypeDropDown.Value;
			 app.ana.photoDiode = logical(app.PhotodiodeCheckBox.Value);
			 
			 app.ana.stimulus = app.StimulusDropDown.Value;
			 app.ana.imageDir = app.ImagesEditField.Value;
			 app.ana.calPath = app.calPathEditField.Value;
			 app.ana.positions = str2num(app.PositionsEditField.Value);
			 app.ana.randomPosition = logical(app.RandomPositionCheckBox.Value);
			 
			 app.ana.size = app.SizedegEditField.Value;
			 app.ana.VEP.mask = logical(app.AddMaskCheckBox.Value);
			 app.ana.VEP.Type = app.TypeDropDown.Value;
			 app.ana.VEP.SF = str2num(app.VEPSFEditField.Value);
			 app.ana.VEP.Contrast = str2num(app.VEPContrastEditField.Value);
			 app.ana.VEP.Flicker =  str2num(app.VEPFlickerEditField.Value);
			 app.ana.VEP.LogSF = logical(app.LogCheckBox.Value);
			 app.ana.VEP.LogContrast = logical(app.LogCheckBox2.Value);
			 app.ana.VEP.sfPeak = app.sfPeakEditField.Value;
			 app.ana.VEP.sfSigma = app.sfSigmaEditField.Value;
			 app.ana.VEP.anglePeak = app.aPeakEditField.Value;
			 app.ana.VEP.angleSigma = app.aSigmaEditField.Value;
			 
			 app.ana.useTracker = logical(app.UseEyeTrackerCheckBox.Value);
			 app.ana.isDummy = logical(app.DummyModeCheckBox.Value);
			 
			 app.ana.tracker = app.TrackerDropDown.Value;
			 app.ana.trackingMode = app.TrackingmodeDropDown.Value;
			 app.ana.eyeUsed = app.EyeusedDropDown.Value;
			 app.ana.calStim = app.CalibrationstimulusDropDown.Value;
			 app.ana.sampleRate = str2num(app.SamplerateKnob.Value);
			 
			 app.ana.calFile = app.CalibrationEditField.Value;
			 app.ana.calPos = str2num(app.CalEditField.Value);
			 app.ana.valPos = str2num(app.ValEditField.Value);
			 app.ana.calManual = logical(app.ManualCheckBox.Value);
			 app.ana.reloadPreviousCal = logical(app.ReloadPreviousCheckBox.Value);
			 app.ana.autoPace = logical(app.AutoPaceCheckBox.Value);
			 app.ana.paceDuration = app.PaceDurationEditField.Value;
			 
			 app.ana.initTime = app.INITEditField.Value;
			 app.ana.fixTime = app.FIXEditField.Value;
			 app.ana.radius = str2num(app.RADIUSEditField.Value);
			 app.ana.XFix = app.XEditField.Value;
			 app.ana.YFix = app.YEditField.Value;
			 app.ana.strict = logical(app.StrictEditField.Value);
			 app.ana.graceTime = app.GraceTimeEditField.Value;
			 app.ana.spotSize = app.SpotSizeEditField.Value;
			 app.ana.spotAlpha = app.SpotAlphaEditField.Value;
			 app.ana.spotLine = app.SpotLineEditField.Value;
			 app.ana.nSamples = app.nSamplesEditField.Value;
			 app.ana.w = app.windowEditField.Value;
			 app.ana.smoothEye =  app.smoothEyeDropDown.Value;
			 app.ana.smoothMethod = app.smoothMethodDropDown.Value;
			 app.ana.drawEye = app.DrawEyeDropDown.Value;
			 app.ana.debug = app.DebugModeCheckBox.Value;
			 app.ana.initFix = app.InitiateFixationCheckBox.Value;
			 app.ana.visualFeedback = app.VisualFeedbackCheckBox.Value;
			 
			 app.ana.backgroundColour = str2num(app.BackgroundEditField.Value);
			 
			 app.ana.screenCal = app.calPathEditField.Value;
			 
			 app.ana.plotAxis1 = app.PlotAxis1;
			 app.ana.plotAxis2 = app.PlotAxis2;
			 
		 end
        
        function p = setPath(app)
            s=metaStimulus();
            app.SavePathEditField.Value=s.paths.savedData;
            app.ImagesEditField.Value=[s.paths.parent filesep 'Pictures'];
            p = app.SavePathEditField.Value;
            clear s;
        end
        
        function calculateTime(app)
            l1 = str2num(app.VEPSFEditField.Value);
            if size(l1,1) > size(l1,2)
                l1 = l1(end);
            else 
                l1 = length(l1);
            end
            l2 = str2num(app.VEPContrastEditField.Value);
            if size(l2,1) > size(l2,2)
                l2 = l2(end);
            else 
                l2 = length(l2);
            end
			isBlank = double(app.AddBlankCheckBox.Value);
			reps = ((l1*l2)+isBlank) * app.BlocksEditField.Value;
            t = (reps * app.PlaytimesEditField.Value) + ((reps-1) * app.ITIsEditField.Value);
            t = t / 60;
            app.ExpTimeTextArea.Value = ['Experiment Time ~ ' num2str(t) ' minutes'];
            app.expTimeLabel.Text = ['Experiment Time ~ ' num2str(t) ' minutes'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          r = groot;
            app.BasicTrainingAppUIFigure.Position(1) = (r.ScreenSize(3)/2) - app.BasicTrainingAppUIFigure.Position(3);
            app.BasicTrainingAppUIFigure.Position(2) = r.ScreenSize(4) - app.BasicTrainingAppUIFigure.Position(4);  
			p=app.setPath();
           cd(p);
           n = app.nSamplesEditField.Value;
           f = str2num(app.SamplerateKnob.Value);
           app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
           app.calculateTime();
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
           p=app.setPath();
           cd(p);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runBasicTraining(app.ana)
        end

        % Button pushed function: SP2Button
        function SP2ButtonPushed(app, event)
            p = uigetdir(app.ImagesEditField.Value,'Select an Images Folder');
            app.ImagesEditField.Value = p;
        end

        % Button pushed function: eyeCALButton
        function eyeCALButtonPushed(app, event)
            [f,p]=uigetfile('*.mat','Please load Tobii Calibration MAT file');
            if isnumeric(f);return;end   
            app.CalibrationEditField.Value = [p f];
        end

        % Value changed function: TrackerDropDown
        function TrackerDropDownValueChanged(app, event)
            value = app.TrackerDropDown.Value;
            switch value
                case 'Tobii TX300'
                    app.TrackingmodeDropDown.Enable = true;
                    app.EyeusedDropDown.Enable = true;
                    app.TrackingmodeDropDown.Items = {'Default','Infant','Bright light'};
                    app.TrackingmodeDropDown.Value = 'Default';
                    app.SamplerateKnob.Items = {'60', '120','300'};
                    app.SamplerateKnob.Value = '300';
                    app.SamplerateKnob.Enable = true;
                case 'Tobii Pro Spectrum'
                    app.TrackingmodeDropDown.Enable = true;
                    app.EyeusedDropDown.Enable = true;
                    app.TrackingmodeDropDown.Items = {'human','macaque'};
                    app.TrackingmodeDropDown.Value = 'human';
                    app.SamplerateKnob.Items = {'60', '90','120','150','300','600','1200'};
                    app.SamplerateKnob.Value = '300';
                    app.SamplerateKnob.Enable = true;
                case 'Tobii 4C'
                    app.EyeusedDropDown.Enable = false;
                    app.TrackingmodeDropDown.Enable = false;
                    app.SamplerateKnob.Items = {'60','90'};
                    app.SamplerateKnob.Value = '90';
                    app.SamplerateKnob.Enable = false;
            end
        end

        % Value changed function: SamplerateKnob
        function SamplerateKnobValueChanged(app, event)
            value = app.SamplerateKnob.Value;
            n = app.nSamplesEditField.Value;
            f = str2num(app.SamplerateKnob.Value);
            app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
        end

        % Value changed function: nSamplesEditField
        function nSamplesEditFieldValueChanged(app, event)
            value = app.nSamplesEditField.Value;
            n = app.nSamplesEditField.Value;
            f = str2num(app.SamplerateKnob.Value);
            app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
        end

        % Button pushed function: calPathButton
        function calPathButtonPushed(app, event)
         [f,p]=uigetfile('*.mat','Please Load calibrateLuminance MAT file');
         if isnumeric(f);return;end   
         app.calPathEditField.Value = [p f];
        end

        % Value changed function: VEPSFEditField
        function VEPSFEditFieldValueChanged(app, event)
            value = app.VEPSFEditField.Value;
            app.calculateTime();
        end

        % Callback function: BlocksEditField, ITIsEditField, 
        % PlaytimesEditField, VEPContrastEditField, 
        % VEPContrastEditField, VEPSFEditField
        function VEPContrastEditFieldValueChanged(app, event)
            value = app.VEPContrastEditField.Value;
            app.calculateTime();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasicTrainingAppUIFigure = uifigure('Visible', 'off');
            app.BasicTrainingAppUIFigure.Name = 'Basic Training App';
            app.TabGroup = uitabgroup(app.BasicTrainingAppUIFigure);
            app.GeneralSettingsTab = uitab(app.TabGroup);
            app.GeneralSettingsTab.Title = 'General Settings';
            app.OptionsPanel = uipanel(app.GeneralSettingsTab);
            app.OptionsPanel.Title = 'Options';
            app.GeneralGrid = uigridlayout(app.OptionsPanel);
            app.StartCheckBox = uicheckbox(app.GeneralGrid);
            app.StartCheckBox.Text = 'Start?';
            app.DuringCheckBox = uicheckbox(app.GeneralGrid);
            app.DuringCheckBox.Text = 'During?';
            app.EndCheckBox = uicheckbox(app.GeneralGrid);
            app.EndCheckBox.Text = 'End?';
            app.EndCheckBox.Value = true;
            app.SPButton = uibutton(app.GeneralGrid, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Text = '';
            app.InfoEditFieldLabel = uilabel(app.GeneralGrid);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GeneralGrid, 'text');
            app.SubjectEditFieldLabel = uilabel(app.GeneralGrid);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GeneralGrid, 'text');
            app.SubjectEditField.Tooltip = {'Enter subject name'};
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.GeneralGrid);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GeneralGrid, 'text');
            app.SavePathEditField.Value = '/home/cog5/Desktop/IsoLum/';
            app.StimulusDropDownLabel = uilabel(app.GeneralGrid);
            app.StimulusDropDownLabel.Text = 'Stimulus';
            app.StimulusDropDown = uidropdown(app.GeneralGrid);
            app.StimulusDropDown.Items = {'Dancing Monkey', 'VEP', 'Pictures', 'Gaze Training', 'Fixation Spot', 'Social Movie'};
            app.StimulusDropDown.Value = 'Dancing Monkey';
            app.SP2Button = uibutton(app.GeneralGrid, 'push');
            app.SP2Button.ButtonPushedFcn = createCallbackFcn(app, @SP2ButtonPushed, true);
            app.SP2Button.Icon = 'go-to-file.png';
            app.SP2Button.Text = '';
            app.ImagesEditFieldLabel = uilabel(app.GeneralGrid);
            app.ImagesEditFieldLabel.Text = 'Images';
            app.ImagesEditField = uieditfield(app.GeneralGrid, 'text');
            app.ImagesEditField.Value = '/home/cog5/Documents/Monkey-Pictures';
            app.RandomPositionCheckBox = uicheckbox(app.GeneralGrid);
            app.RandomPositionCheckBox.Tooltip = {'randomise position from the above list'};
            app.RandomPositionCheckBox.Text = 'Random Position';
            app.RandomPositionCheckBox.Value = true;
            app.PositionsEditFieldLabel = uilabel(app.GeneralGrid);
            app.PositionsEditFieldLabel.Text = 'Positions';
            app.PositionsEditField = uieditfield(app.GeneralGrid, 'text');
            app.PositionsEditField.Value = '-12 -10; -12 10; 0 0; 12 -10; 12 10';
            app.LabJackTriggerCheckBox = uicheckbox(app.GeneralGrid);
            app.LabJackTriggerCheckBox.Tooltip = {'Use LabJack trigger?'};
            app.LabJackTriggerCheckBox.Text = 'LabJack Trigger?';
            app.GOButton = uibutton(app.GeneralGrid, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.DebugModeCheckBox = uicheckbox(app.GeneralGrid);
            app.DebugModeCheckBox.Tooltip = {'debugging mode?'};
            app.DebugModeCheckBox.Text = 'Debug Mode?';
            app.PlaytimesEditFieldLabel = uilabel(app.GeneralGrid);
            app.PlaytimesEditFieldLabel.Text = 'Play time (s)';
            app.PlaytimesEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.PlaytimesEditField.ValueChangedFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.PlaytimesEditField.Value = 1;
            app.DistancecmEditFieldLabel = uilabel(app.GeneralGrid);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.DistancecmEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.DistancecmEditField.Value = 60;
            app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralGrid);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.PixelsPerCmEditField.Value = 36;
            app.ITIsEditFieldLabel = uilabel(app.GeneralGrid);
            app.ITIsEditFieldLabel.Tooltip = {'Inter trial interval'};
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.ITIsEditField.ValueChangedFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.ITIsEditField.Value = 1;
            app.TimeOutsEditFieldLabel = uilabel(app.GeneralGrid);
            app.TimeOutsEditFieldLabel.Text = 'Time Out (s)';
            app.TimeOutsEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.TimeOutsEditField.Tooltip = {'Time out when incorrect'};
            app.TimeOutsEditField.Value = 4;
            app.BlocksEditFieldLabel = uilabel(app.GeneralGrid);
            app.BlocksEditFieldLabel.Tooltip = {'Inter trial interval'};
            app.BlocksEditFieldLabel.Text = 'Blocks #';
            app.BlocksEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.BlocksEditField.ValueChangedFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.BlocksEditField.Value = 20;
            app.BackgroundEditFieldLabel = uilabel(app.GeneralGrid);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.GeneralGrid, 'text');
            app.BackgroundEditField.Value = '0.5 0.5 0.5';
            app.PhotodiodeCheckBox = uicheckbox(app.GeneralGrid);
            app.PhotodiodeCheckBox.Tooltip = {'Show photodiode Square?'; ''};
            app.PhotodiodeCheckBox.Text = 'Photodiode?';
            app.PhotodiodeCheckBox.Value = true;
            app.calPathButton = uibutton(app.GeneralGrid, 'push');
            app.calPathButton.ButtonPushedFcn = createCallbackFcn(app, @calPathButtonPushed, true);
            app.calPathButton.Icon = 'go-to-file.png';
            app.calPathButton.Text = '';
            app.calPathEditFieldLabel = uilabel(app.GeneralGrid);
            app.calPathEditFieldLabel.Text = 'Calibration';
            app.calPathEditField = uieditfield(app.GeneralGrid, 'text');
            app.UseArduinoCheckBox = uicheckbox(app.GeneralGrid);
            app.UseArduinoCheckBox.Tooltip = {'Use Arduino?'};
            app.UseArduinoCheckBox.Text = 'Use Arduino?';
            app.UseArduinoCheckBox.Value = true;
            app.expTimeLabel = uilabel(app.GeneralGrid);
            app.expTimeLabel.Tooltip = {'Inter trial interval'};
            app.expTimeLabel.Text = '';
            app.lJTypeDropDown = uidropdown(app.GeneralGrid);
            app.lJTypeDropDown.Items = {'T4', 'U3'};
            app.lJTypeDropDown.Value = 'T4';
            app.InitiateFixationCheckBox = uicheckbox(app.GeneralGrid);
            app.InitiateFixationCheckBox.Tooltip = {'Enable an initial fixation spot the subject needs to engage before the stimulus is presented'};
            app.InitiateFixationCheckBox.Text = {'Initiate Fixation?'; ''};
            app.InitiateFixationCheckBox.Value = true;
            app.VisualFeedbackCheckBox = uicheckbox(app.GeneralGrid);
            app.VisualFeedbackCheckBox.Tooltip = {'green / red visual feedback on trial result?'};
            app.VisualFeedbackCheckBox.Text = 'Visual Feedback?';
            app.EyetrackersettingsPanel = uipanel(app.GeneralSettingsTab);
            app.EyetrackersettingsPanel.Title = 'Eyetracker settings';
            app.StrictEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.StrictEditFieldLabel.Text = 'Strict';
            app.StrictEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.StrictEditField.Limits = [0 1];
            app.StrictEditField.RoundFractionalValues = 'on';
            app.StrictEditField.ValueDisplayFormat = '%.0f';
            app.StrictEditField.Tooltip = {'0 means use the native size of the image/movie'};
            app.StrictEditField.Value = 1;
            app.INITEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.INITEditFieldLabel.Text = 'INIT';
            app.INITEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.INITEditField.Limits = [0 100];
            app.INITEditField.Tooltip = {'Time to initiate fixation'};
            app.INITEditField.Value = 3;
            app.FIXEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.FIXEditFieldLabel.Text = 'FIX';
            app.FIXEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.FIXEditField.Limits = [0 100];
            app.FIXEditField.Tooltip = {'Minimum time to stay fixated'};
            app.FIXEditField.Value = 0.6;
            app.TrackerDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackerDropDownLabel.Text = 'Tracker';
            app.TrackerDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackerDropDown.Items = {'Tobii Pro Spectrum', 'Tobii TX300', 'Tobii 4C'};
            app.TrackerDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackerDropDownValueChanged, true);
            app.TrackerDropDown.Value = 'Tobii Pro Spectrum';
            app.TrackingmodeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDownLabel.Text = 'Tracking mode';
            app.TrackingmodeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDown.Items = {'human', 'macaque'};
            app.TrackingmodeDropDown.Value = 'human';
            app.CalEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalEditFieldLabel.Text = 'Cal';
            app.CalEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.CalEditField.Value = '[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]';
            app.CalibrationstimulusDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDownLabel.Text = 'Calibration stimulus';
            app.CalibrationstimulusDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDown.Items = {'animated', 'movie', 'normal'};
            app.CalibrationstimulusDropDown.Value = 'animated';
            app.ValEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.ValEditField.Value = '[ 0.5 0.5 ]';
            app.AutoPaceCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.AutoPaceCheckBox.Text = 'Auto Pace';
            app.UseEyeTrackerCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.UseEyeTrackerCheckBox.Text = 'Use Eye Tracker?';
            app.DummyModeCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.DummyModeCheckBox.Text = 'Dummy Mode?';
            app.eyeCALButton = uibutton(app.EyetrackersettingsPanel, 'push');
            app.eyeCALButton.ButtonPushedFcn = createCallbackFcn(app, @eyeCALButtonPushed, true);
            app.eyeCALButton.Icon = 'go-to-file.png';
            app.eyeCALButton.Text = '';
            app.CalibrationEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.PaceDurationEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.PaceDurationEditField.Tooltip = {'Pace duration (s)'};
            app.PaceDurationEditField.Value = 0.8;
            app.SpotSizeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotSizeEditFieldLabel.Text = 'Spot Size';
            app.SpotSizeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotSizeEditField.Limits = [0 100];
            app.SpotSizeEditField.Value = 0.7;
            app.SamplerateKnobLabel = uilabel(app.EyetrackersettingsPanel);
            app.SamplerateKnobLabel.Text = 'Sample rate';
            app.SamplerateKnob = uiknob(app.EyetrackersettingsPanel, 'discrete');
            app.SamplerateKnob.Items = {'60', '90', '120', '150', '300', '600', '1200'};
            app.SamplerateKnob.ValueChangedFcn = createCallbackFcn(app, @SamplerateKnobValueChanged, true);
            app.SamplerateKnob.Value = '300';
            app.ReloadPreviousCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.ReloadPreviousCheckBox.Tooltip = {'Reload previous calibration file automatically?'};
            app.ReloadPreviousCheckBox.Text = 'Reload Previous?';
            app.ReloadPreviousCheckBox.Value = true;
            app.EyeusedDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.EyeusedDropDownLabel.Text = 'Eye used';
            app.EyeusedDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.EyeusedDropDown.Items = {'both', 'left', 'right'};
            app.EyeusedDropDown.Value = 'both';
            app.EyeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.EyeDropDownLabel.Text = {'Eye'; ''};
            app.smoothEyeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.smoothEyeDropDown.Items = {'both', 'left', 'right'};
            app.smoothEyeDropDown.Tooltip = {'smoothing use eye data?'};
            app.smoothEyeDropDown.Value = 'both';
            app.MethodDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.smoothMethodDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.smoothMethodDropDown.Items = {'median', 'heuristic1', 'heuristic2', 'savitsky-golay', 'basic'};
            app.smoothMethodDropDown.Tooltip = {'smoothing method'};
            app.smoothMethodDropDown.Value = 'median';
            app.wEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.wEditFieldLabel.Text = 'w';
            app.windowEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.windowEditField.Limits = [1 100];
            app.windowEditField.RoundFractionalValues = 'on';
            app.windowEditField.ValueDisplayFormat = '%.0f';
            app.windowEditField.Tooltip = {'smoothing window'};
            app.windowEditField.Value = 3;
            app.EditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.EditFieldLabel.Text = '#';
            app.nSamplesEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.nSamplesEditField.Limits = [1 200];
            app.nSamplesEditField.RoundFractionalValues = 'on';
            app.nSamplesEditField.ValueDisplayFormat = '%.0f';
            app.nSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nSamplesEditFieldValueChanged, true);
            app.nSamplesEditField.Tooltip = {'# of samples to smooth'};
            app.nSamplesEditField.Value = 4;
            app.msLabel = uilabel(app.EyetrackersettingsPanel);
            app.msLabel.Text = '20ms';
            app.ManualCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.ManualCheckBox.Text = 'Manual';
            app.ManualCheckBox.Value = true;
            app.SpotAlphaEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotAlphaEditFieldLabel.Text = 'Spot Alpha';
            app.SpotAlphaEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotAlphaEditField.Limits = [0 1];
            app.SpotAlphaEditField.Value = 0.25;
            app.SpotLineEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotLineEditFieldLabel.Text = 'Spot Line';
            app.SpotLineEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotLineEditField.Limits = [0 100];
            app.SpotLineEditField.Value = 0.05;
            app.XEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.XEditField.Limits = [-100 100];
            app.XEditField.Tooltip = {'X position in degrees from the center of the screen'};
            app.YEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.YEditField.Limits = [-100 100];
            app.YEditField.Tooltip = {'Y position in degrees from the center of the screen'};
            app.GraceTimeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.GraceTimeEditFieldLabel.Text = 'GraceTime';
            app.GraceTimeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.GraceTimeEditField.Limits = [0 100];
            app.GraceTimeEditField.RoundFractionalValues = 'on';
            app.GraceTimeEditField.ValueDisplayFormat = '%.0f';
            app.GraceTimeEditField.Tooltip = {'The time the monkey has to move their eyes before the fixation window is enforced suring stimulus presentation'; ''};
            app.RADIUSEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.RADIUSEditFieldLabel.Text = 'RADIUS';
            app.RADIUSEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.RADIUSEditField.Tooltip = {'Radius of fixation window'};
            app.RADIUSEditField.Value = '2';
            app.DrawEyeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.DrawEyeDropDownLabel.Text = 'Draw Eye?';
            app.DrawEyeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.DrawEyeDropDown.Items = {'No', 'On screen', 'New Screen'};
            app.DrawEyeDropDown.Value = 'No';
            app.VEPSettingsTab = uitab(app.TabGroup);
            app.VEPSettingsTab.Title = 'VEP Settings';
            app.VEPStimuliPanel = uipanel(app.VEPSettingsTab);
            app.VEPStimuliPanel.Title = 'VEP Stimuli';
            app.VEPGrid = uigridlayout(app.VEPStimuliPanel);
            app.VEPSFEditField = uieditfield(app.VEPGrid, 'text');
            app.VEPSFEditField.ValueChangedFcn = createCallbackFcn(app, @VEPSFEditFieldValueChanged, true);
            app.VEPSFEditField.ValueChangingFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.VEPSFEditField.Value = '0 0.05 0.1 0.5';
            app.VEPSFEditFieldLabel = uilabel(app.VEPGrid);
            app.VEPSFEditFieldLabel.Text = 'SF (Hz)';
            app.VEPContrastEditFieldLabel = uilabel(app.VEPGrid);
            app.VEPContrastEditFieldLabel.Text = 'Contrast';
            app.VEPContrastEditField = uieditfield(app.VEPGrid, 'text');
            app.VEPContrastEditField.ValueChangedFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.VEPContrastEditField.ValueChangingFcn = createCallbackFcn(app, @VEPContrastEditFieldValueChanged, true);
            app.VEPContrastEditField.Value = '0.025 0.1 0.5';
            app.LogCheckBox = uicheckbox(app.VEPGrid);
            app.LogCheckBox.Text = 'Log';
            app.VEPFlickerEditField = uieditfield(app.VEPGrid, 'text');
            app.VEPFlickerEditField.Value = '0.1';
            app.VEPFlickerEditFieldLabel = uilabel(app.VEPGrid);
            app.VEPFlickerEditFieldLabel.Text = 'Flicker (s)';
            app.TypeDropDownLabel = uilabel(app.VEPGrid);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.VEPGrid);
            app.TypeDropDown.Items = {'Checkerboard', 'Square', 'Sin', 'LogGabor'};
            app.TypeDropDown.Value = 'Sin';
            app.LogCheckBox2 = uicheckbox(app.VEPGrid);
            app.LogCheckBox2.Text = 'Log';
            app.sfPeakEditFieldLabel = uilabel(app.VEPGrid);
            app.sfPeakEditFieldLabel.Text = 'sfPeak';
            app.sfPeakEditField = uieditfield(app.VEPGrid, 'numeric');
            app.sfPeakEditField.Value = 1;
            app.sfSigmaEditFieldLabel = uilabel(app.VEPGrid);
            app.sfSigmaEditFieldLabel.Text = 'sfSigma';
            app.sfSigmaEditField = uieditfield(app.VEPGrid, 'numeric');
            app.sfSigmaEditField.Value = 0.01;
            app.aPeakEditFieldLabel = uilabel(app.VEPGrid);
            app.aPeakEditFieldLabel.Text = 'aPeak';
            app.aPeakEditField = uieditfield(app.VEPGrid, 'numeric');
            app.aSigmaEditFieldLabel = uilabel(app.VEPGrid);
            app.aSigmaEditFieldLabel.Text = 'aSigma';
            app.aSigmaEditField = uieditfield(app.VEPGrid, 'numeric');
            app.aSigmaEditField.Value = 20;
            app.SizedegEditFieldLabel = uilabel(app.VEPGrid);
            app.SizedegEditFieldLabel.Text = 'Size (deg)';
            app.SizedegEditField = uieditfield(app.VEPGrid, 'numeric');
            app.SizedegEditField.Tooltip = {'0 means use the native size of the image/movie'};
            app.AddMaskCheckBox = uicheckbox(app.VEPGrid);
            app.AddMaskCheckBox.Text = 'Add Mask?';
            app.ExpTimeTextArea = uitextarea(app.VEPGrid);
            app.AddBlankCheckBox = uicheckbox(app.VEPGrid);
            app.AddBlankCheckBox.Text = 'Add Blank?';
            app.AddBlankCheckBox.Value = true;
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.ResultsPanel = uipanel(app.PlotsTab);
            app.ResultsPanel.Title = 'Results';
            app.PlotAxis1 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis1, 'Rewards')
            ylabel(app.PlotAxis1, '#')
            app.PlotAxis2 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis2, 'Responses')
            ylabel(app.PlotAxis2, '#')
            % Show the figure after all components are created
            app.BasicTrainingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasicTrainingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasicTrainingAppUIFigure)
        end
    end
end