classdef behaviouralAcuity < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		BehaviouralAcuityTestsUIFigure  matlab.ui.Figure
		TabGroup                        matlab.ui.container.TabGroup
		ControlTab                      matlab.ui.container.Tab
		RunPanel                        matlab.ui.container.Panel
		GOButton                        matlab.ui.control.Button
		ExitButton                      matlab.ui.control.Button
		SaveButton                      matlab.ui.control.Button
		TaskStimulusOptionsPanel        matlab.ui.container.Panel
		SFEditField                     matlab.ui.control.NumericEditField
		SFLabel                         matlab.ui.control.Label
		FadeFrameTargetEditField        matlab.ui.control.EditField
		stopruleEditField               matlab.ui.control.NumericEditField
		stopruleEditFieldLabel          matlab.ui.control.Label
		FadeFinalEditField              matlab.ui.control.EditField
		KeepBlanksEditField             matlab.ui.control.NumericEditField
		KeepBlanksEditFieldLabel        matlab.ui.control.Label
		GratingEditField                matlab.ui.control.NumericEditField
		GratingEditFieldLabel           matlab.ui.control.Label
		alphaEditField                  matlab.ui.control.NumericEditField
		FadeFrameEditField              matlab.ui.control.EditField
		FadeFrameEditFieldLabel         matlab.ui.control.Label
		SwitchtimesEditField            matlab.ui.control.EditField
		SwitchtimesEditFieldLabel       matlab.ui.control.Label
		FixOFFsEditField                matlab.ui.control.EditField
		FixOFFsEditFieldLabel           matlab.ui.control.Label
		TargetONsEditField              matlab.ui.control.EditField
		TargetONsEditFieldLabel         matlab.ui.control.Label
		InitTargetsEditField            matlab.ui.control.NumericEditField
		InitTargetsEditFieldLabel       matlab.ui.control.Label
		FixTargetsEditField             matlab.ui.control.NumericEditField
		FixTargetsEditFieldLabel        matlab.ui.control.Label
		DiskColorEditField              matlab.ui.control.EditField
		DiskColorEditFieldLabel         matlab.ui.control.Label
		alphagrainEditField             matlab.ui.control.NumericEditField
		alphagrainEditFieldLabel        matlab.ui.control.Label
		betagrainEditField              matlab.ui.control.NumericEditField
		betagrainEditFieldLabel         matlab.ui.control.Label
		LogbetaCheckBox                 matlab.ui.control.CheckBox
		TestButton                      matlab.ui.control.Button
		marginaliseEditField            matlab.ui.control.EditField
		marginaliseEditFieldLabel       matlab.ui.control.Label
		betapriorEditField              matlab.ui.control.NumericEditField
		betapriorEditFieldLabel         matlab.ui.control.Label
		betaSDEditField                 matlab.ui.control.NumericEditField
		betaSDEditFieldLabel            matlab.ui.control.Label
		alphapriorEditField             matlab.ui.control.NumericEditField
		alphapriorEditFieldLabel        matlab.ui.control.Label
		alphaSDEditField                matlab.ui.control.NumericEditField
		alphaSDEditFieldLabel           matlab.ui.control.Label
		LogalphaCheckBox                matlab.ui.control.CheckBox
		PriorsSwitch                    matlab.ui.control.Switch
		PriorsSwitchLabel               matlab.ui.control.Label
		ConstrastRangeEditField         matlab.ui.control.EditField
		ConstrastRangeEditFieldLabel    matlab.ui.control.Label
		lambdaEditField                 matlab.ui.control.NumericEditField
		lambdaEditFieldLabel            matlab.ui.control.Label
		betamaxEditField                matlab.ui.control.NumericEditField
		betamaxEditFieldLabel           matlab.ui.control.Label
		gammaEditField                  matlab.ui.control.NumericEditField
		gammaEditFieldLabel             matlab.ui.control.Label
		PFDropDown                      matlab.ui.control.DropDown
		PFDropDownLabel                 matlab.ui.control.Label
		SigmaEditField                  matlab.ui.control.NumericEditField
		SigmaEditFieldLabel             matlab.ui.control.Label
		TargetdegEditField              matlab.ui.control.NumericEditField
		TargetdegEditFieldLabel         matlab.ui.control.Label
		StimulusSizeEditField           matlab.ui.control.NumericEditField
		StimulusSizeEditFieldLabel      matlab.ui.control.Label
		StairCaseSwitch                 matlab.ui.control.Switch
		StairCaseSwitchLabel            matlab.ui.control.Label
		GeneralPanel                    matlab.ui.container.Panel
		ColorDepthDropDown              matlab.ui.control.DropDown
		ColorDepthDropDownLabel         matlab.ui.control.Label
		OperatorPxCmEditField           matlab.ui.control.NumericEditField
		OperatorPxCmEditFieldLabel      matlab.ui.control.Label
		OperatorEditField               matlab.ui.control.NumericEditField
		OperatorEditFieldLabel          matlab.ui.control.Label
		PunishIFIEditField              matlab.ui.control.NumericEditField
		PunishIFIEditFieldLabel         matlab.ui.control.Label
		IFIEditField                    matlab.ui.control.NumericEditField
		IFIEditFieldLabel               matlab.ui.control.Label
		nBlocksEditField                matlab.ui.control.NumericEditField
		nBlocksEditFieldLabel           matlab.ui.control.Label
		TaskTypeListBox                 matlab.ui.control.ListBox
		TaskTypeListBoxLabel            matlab.ui.control.Label
		BackgroundColorEditField        matlab.ui.control.EditField
		BackgroundColorEditFieldLabel   matlab.ui.control.Label
		DebugModeSwitch                 matlab.ui.control.RockerSwitch
		DebugModeSwitchLabel            matlab.ui.control.Label
		PixelsCmEditField               matlab.ui.control.NumericEditField
		PixelsCmEditFieldLabel          matlab.ui.control.Label
		DistanceEditField               matlab.ui.control.NumericEditField
		DistanceEditFieldLabel          matlab.ui.control.Label
		CalibrationEditField            matlab.ui.control.EditField
		CalibrationEditFieldLabel       matlab.ui.control.Label
		SavePathEditField               matlab.ui.control.EditField
		SavePathEditFieldLabel          matlab.ui.control.Label
		SubjectEditField                matlab.ui.control.EditField
		SubjectEditFieldLabel           matlab.ui.control.Label
		InfoEditField                   matlab.ui.control.EditField
		InfoEditFieldLabel              matlab.ui.control.Label
		SPButton                        matlab.ui.control.Button
		CButton                         matlab.ui.control.Button
		EyeTrackerTab                   matlab.ui.container.Tab
		EyetrackersettingsPanel         matlab.ui.container.Panel
		CalibrationSmoothingPanel       matlab.ui.container.Panel
		CalOptionsTextArea              matlab.ui.control.TextArea
		CalOptionsTextAreaLabel         matlab.ui.control.Label
		nSamplesEditField               matlab.ui.control.NumericEditField
		EditFieldLabel                  matlab.ui.control.Label
		windowEditField                 matlab.ui.control.NumericEditField
		wEditFieldLabel                 matlab.ui.control.Label
		smoothMethodDropDown            matlab.ui.control.DropDown
		MethodDropDownLabel             matlab.ui.control.Label
		smoothEyeDropDown               matlab.ui.control.DropDown
		EyeDropDownLabel                matlab.ui.control.Label
		ManualCheckBox                  matlab.ui.control.CheckBox
		ReloadPreviousCheckBox          matlab.ui.control.CheckBox
		PaceDurationEditField           matlab.ui.control.NumericEditField
		CalibrationEditField_2          matlab.ui.control.EditField
		CalibrationEditField_2Label     matlab.ui.control.Label
		eyeCALButton                    matlab.ui.control.Button
		AutoPaceCheckBox                matlab.ui.control.CheckBox
		ValEditField                    matlab.ui.control.EditField
		ValEditFieldLabel               matlab.ui.control.Label
		CalEditField                    matlab.ui.control.EditField
		CalEditFieldLabel               matlab.ui.control.Label
		msLabel                         matlab.ui.control.Label
		FixationCrossParametersPanel    matlab.ui.container.Panel
		GraceTimeEditField              matlab.ui.control.NumericEditField
		GraceTimeEditFieldLabel         matlab.ui.control.Label
		SpotLineEditField               matlab.ui.control.NumericEditField
		SpotLineEditFieldLabel          matlab.ui.control.Label
		SpotAlphaEditField              matlab.ui.control.NumericEditField
		SpotAlphaEditFieldLabel         matlab.ui.control.Label
		SpotSizeEditField               matlab.ui.control.NumericEditField
		SpotSizeEditFieldLabel          matlab.ui.control.Label
		InitialFixationWindowPanel      matlab.ui.container.Panel
		STRICTEditFieldLabel            matlab.ui.control.Label
		STRICTEditField                 matlab.ui.control.NumericEditField
		RADIUSEditFieldLabel            matlab.ui.control.Label
		RadiusEditField                 matlab.ui.control.EditField
		YEditFieldLabel                 matlab.ui.control.Label
		YEditField                      matlab.ui.control.NumericEditField
		XEditFieldLabel                 matlab.ui.control.Label
		XEditField                      matlab.ui.control.NumericEditField
		FIXEditFieldLabel               matlab.ui.control.Label
		FIXEditField                    matlab.ui.control.NumericEditField
		INITEditFieldLabel              matlab.ui.control.Label
		INITEditField                   matlab.ui.control.NumericEditField
		DrawEyeDropDown                 matlab.ui.control.DropDown
		DrawEyeDropDownLabel            matlab.ui.control.Label
		SamplerateKnob                  matlab.ui.control.DiscreteKnob
		SamplerateKnobLabel             matlab.ui.control.Label
		DummyModeCheckBox               matlab.ui.control.CheckBox
		UseEyeTrackerCheckBox           matlab.ui.control.CheckBox
		EyeusedDropDown                 matlab.ui.control.DropDown
		EyeusedDropDownLabel            matlab.ui.control.Label
		CalibrationstimulusDropDown     matlab.ui.control.DropDown
		CalibrationstimulusDropDownLabel  matlab.ui.control.Label
		TrackingmodeDropDown            matlab.ui.control.DropDown
		TrackingmodeDropDownLabel       matlab.ui.control.Label
		TrackerDropDown                 matlab.ui.control.DropDown
		TrackerDropDownLabel            matlab.ui.control.Label
		PlotsTab                        matlab.ui.container.Tab
		Data3Axes                       matlab.ui.control.UIAxes
		Data4Axes                       matlab.ui.control.UIAxes
		Data2Axes                       matlab.ui.control.UIAxes
		Data1Axes                       matlab.ui.control.UIAxes
	end


	properties (Access = private)
		ana % main settings
	end

	methods (Access = private)

		function parseSettings(app)
			app.ana					= struct();
			app.ana.uuid			= num2str(dec2hex(floor((now - floor(now))*1e10)));
			app.ana.subject			= app.SubjectEditField.Value;
			app.ana.comments		= app.InfoEditField.Value;
			app.ana.ResultDir		= app.SavePathEditField.Value;
			app.ana.gammaTable		= app.CalibrationEditField.Value;
			app.ana.bitDepth		= app.ColorDepthDropDown.Value;
			app.ana.pixelsPerCm		= app.PixelsCmEditField.Value;
			app.ana.distance		= app.DistanceEditField.Value;
			app.ana.nBlocks			= app.nBlocksEditField.Value;
			app.ana.IFI				= app.IFIEditField.Value;
			app.ana.punishIFI		= app.PunishIFIEditField.Value;
			app.ana.operatorPercent = app.OperatorEditField.Value;
			app.ana.operatorPPC		= app.OperatorPxCmEditField.Value;
			app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value); %#ok<*ST2NM>

			% Stimulus options
			app.ana.switchTime		= str2num(app.SwitchtimesEditField.Value);
			app.ana.targetON		= str2num(app.TargetONsEditField.Value);
			app.ana.fixOFF			= str2num(app.FixOFFsEditField.Value);
			app.ana.fixOFF2			= app.alphaEditField.Value;
			app.ana.fadeAmount		= str2num(app.FadeFrameEditField.Value);
			app.ana.fadeAmountTarget = str2num(app.FadeFrameTargetEditField.Value);
			app.ana.fadeFinal		= str2num(app.FadeFinalEditField.Value);
			app.ana.discSize		= app.StimulusSizeEditField.Value;
			app.ana.targetPosition	= app.TargetdegEditField.Value;
			app.ana.initTarget		= app.InitTargetsEditField.Value;
			app.ana.fixTarget		= app.FixTargetsEditField.Value;
			app.ana.sigma			= app.SigmaEditField.Value;
			app.ana.gProbability	= app.GratingEditField.Value;
			app.ana.keepBlank		= app.KeepBlanksEditField.Value;
			app.ana.contrastRange	= str2num(app.ConstrastRangeEditField.Value);
			app.ana.SF				= app.SFEditField.Value;

			% Staircase
			app.ana.betaMax			= app.betamaxEditField.Value;
			app.ana.gamma			= app.gammaEditField.Value;
			app.ana.lambda			= app.lambdaEditField.Value;
			app.ana.marginalize		= [];
			val						= app.marginaliseEditField.Value;
			if ~isempty(regexpi(val,'threshold|slope|lapse|guess',"once"))
				app.ana.marginalize = val;
			end
			app.ana.alphaPrior		= app.alphapriorEditField.Value;
			app.ana.alphaSD			= app.alphaSDEditField.Value;
			app.ana.betaPrior		= app.betapriorEditField.Value;
			app.ana.betaSD			= app.betaSDEditField.Value;
			app.ana.alphaGrain		= app.alphagrainEditField.Value;
			app.ana.betaGrain		= app.betagrainEditField.Value;
			app.ana.stopRule		= app.stopruleEditField.Value;
			app.ana.PF				= eval(['@PAL_' app.PFDropDown.Value]);
			app.ana.logTransform	= logical(app.LogalphaCheckBox.Value);
			app.ana.logSlope		= logical(app.LogbetaCheckBox.Value);
			app.ana.myFunc			= app.TaskTypeListBox.Value;

			% EYETRACKER
			app.ana.useTracker		= logical(app.UseEyeTrackerCheckBox.Value);
			app.ana.isDummy			= logical(app.DummyModeCheckBox.Value);
			app.ana.tracker			= app.TrackerDropDown.Value;
			app.ana.trackingMode	= app.TrackingmodeDropDown.Value;
			app.ana.eyeUsed			= app.EyeusedDropDown.Value;
			app.ana.calStim			= app.CalibrationstimulusDropDown.Value;
			app.ana.sampleRate		= str2num(app.SamplerateKnob.Value);

			app.ana.calFile			= app.CalibrationEditField.Value;
			app.ana.calPos			= str2num(app.CalEditField.Value);
			app.ana.valPos			= str2num(app.ValEditField.Value);
			app.ana.calManual		= logical(app.ManualCheckBox.Value);
			app.ana.reloadPreviousCal = logical(app.ReloadPreviousCheckBox.Value);
			app.ana.autoPace		= logical(app.AutoPaceCheckBox.Value);
			app.ana.paceDuration	= app.PaceDurationEditField.Value;

			app.ana.initTime		= app.INITEditField.Value;
			app.ana.fixTime			= app.FIXEditField.Value;
			app.ana.radius			= str2num(app.RadiusEditField.Value);
			app.ana.XFix			= app.XEditField.Value;
			app.ana.YFix			= app.YEditField.Value;
			app.ana.strict			= logical(app.STRICTEditField.Value);
			app.ana.graceTime		= app.GraceTimeEditField.Value;
			app.ana.spotSize		= app.SpotSizeEditField.Value;
			app.ana.spotAlpha		= app.SpotAlphaEditField.Value;
			app.ana.spotLine		= app.SpotLineEditField.Value;
			app.ana.nSamples		= app.nSamplesEditField.Value;
			app.ana.w				= app.windowEditField.Value;
			app.ana.smoothEye		= app.smoothEyeDropDown.Value;
			app.ana.smoothMethod	= app.smoothMethodDropDown.Value;
			app.ana.drawEye			= app.DrawEyeDropDown.Value;


			if strcmpi(app.StairCaseSwitch.Value,'On')
				app.ana.useStaircase = true;
			else
				app.ana.useStaircase = false;
			end
			if strcmpi(app.PriorsSwitch.Value,'On')
				app.ana.usePriors	= true;
			else
				app.ana.usePriors	= false;
			end

			if strcmpi(app.DebugModeSwitch.Value,'On')
				app.ana.debug		= true;
				app.ana.windowed	= [];
			else
				app.ana.debug		= false;
				app.ana.windowed	= [];
			end

			app.ana.plotAxis1		= app.Data1Axes;
			app.ana.plotAxis2		= app.Data2Axes;
			app.ana.plotAxis3		= app.Data3Axes;
			app.ana.plotAxis4		= app.Data4Axes;
		end

		function p = setPath(app)
			s=metaStimulus();
			app.SavePathEditField.Value=s.paths.savedData;
			p = app.SavePathEditField.Value;
			clear s;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			r = groot;
			app.BehaviouralAcuityTestsUIFigure.Position(1) = (r.ScreenSize(3)) - app.BehaviouralAcuityTestsUIFigure.Position(3) - 60;
			app.BehaviouralAcuityTestsUIFigure.Position(2) = r.ScreenSize(4) - app.BehaviouralAcuityTestsUIFigure.Position(4);
			p=app.setPath();
			cd(p);
		end

		% Button pushed function: GOButton
		function GOButtonPushed(app, event)
			event.Source.BusyAction = 'cancel';
			event.Source.Interruptible = 'off';
			app.parseSettings()
			fprintf('\n=========RUN: %s============\n',app.ana.myFunc)
			runBehaviouralAcuity(app.ana);
		end

		% Button pushed function: SaveButton
		function SaveButtonPushed(app, event)
			app.parseSettings();
		end

		% Button pushed function: ExitButton
		function ExitButtonPushed(app, event)
			delete(app);
		end

		% Button pushed function: CButton
		function CButtonPushed(app, event)
			[f, p] = uigetfile('*.mat','Select a Calibration File');
			app.CalibrationEditField.Value = [p f];
		end

		% Button pushed function: SPButton
		function SPButtonPushed(app, event)
			app.SavePathEditField.Value = uigetdir;
		end

		% Value changed function: TaskTypeListBox
		function TaskTypeListBoxValueChanged(app, event)
			value = app.TaskTypeListBox.Value;
			switch value
				case 'AIContrast'

				case 'ConThreshold'

				case 'AIConThreshold'

			end

		end

		% Button pushed function: TestButton
		function TestButtonPushed(app, event)
			range.alpha = app.alphapriorEditField.Value;
			range.beta = app.betapriorEditField.Value;
			range.gamma = app.gammaEditField.Value;
			range.lambda = app.lambdaEditField.Value;
			range.values = app.ConstrastRangeEditField.Value;
			plotPF(range);
		end

		% Value changed function: TrackerDropDown
		function TrackerDropDownValueChanged(app, event)
			value = app.TrackerDropDown.Value;
			switch value
				case 'Tobii TX300'
					app.TrackingmodeDropDown.Enable = true;
					app.EyeusedDropDown.Enable = true;
					app.TrackingmodeDropDown.Items = {'Default','Infant','Bright light'};
					app.TrackingmodeDropDown.Value = 'Default';
					app.SamplerateKnob.Items = {'60', '120','300'};
					app.SamplerateKnob.Value = '300';
					app.SamplerateKnob.Enable = true;
				case 'Tobii Pro Spectrum'
					app.TrackingmodeDropDown.Enable = true;
					app.EyeusedDropDown.Enable = true;
					app.TrackingmodeDropDown.Items = {'human','macaque'};
					app.TrackingmodeDropDown.Value = 'human';
					app.SamplerateKnob.Items = {'60', '90','120','150','300','600','1200'};
					app.SamplerateKnob.Value = '300';
					app.SamplerateKnob.Enable = true;
				case 'Tobii 4C'
					app.EyeusedDropDown.Enable = false;
					app.TrackingmodeDropDown.Enable = false;
					app.SamplerateKnob.Items = {'60','90'};
					app.SamplerateKnob.Value = '90';
					app.SamplerateKnob.Enable = false;
			end
		end

		% Value changed function: SamplerateKnob
		function SamplerateKnobValueChanged(app, event)
			value = app.SamplerateKnob.Value;
			n = app.nSamplesEditField.Value;
			f = str2num(app.SamplerateKnob.Value);
			app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
		end

		% Value changed function: nSamplesEditField
		function nSamplesEditFieldValueChanged(app, event)
			n = app.nSamplesEditField.Value;
			f = str2num(app.SamplerateKnob.Value);
			app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
		end

		% Button pushed function: eyeCALButton
		function eyeCALButtonPushed(app, event)
			[f,p]=uigetfile('*.mat','Please load Tobii Calibration MAT file');
			if isnumeric(f);return;end
			app.CalibrationEditField.Value = [p f];
		end

		% Value changed function: StairCaseSwitch
		function StairCaseSwitchValueChanged(app, event)

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.BehaviouralAcuityTestsUIFigure = uifigure('Visible', 'off');
			app.BehaviouralAcuityTestsUIFigure.Name = 'Behavioural Acuity Tests';
			app.BehaviouralAcuityTestsUIFigure.Icon = 'acuity.png';
			app.TabGroup = uitabgroup(app.BehaviouralAcuityTestsUIFigure);
			app.ControlTab = uitab(app.TabGroup);
			app.ControlTab.Title = 'Control';
			app.GeneralPanel = uipanel(app.ControlTab);
			app.GeneralPanel.Title = 'General';
			app.CButton = uibutton(app.GeneralPanel, 'push');
			app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
			app.CButton.Icon = 'go-to-file.png';
			app.CButton.Text = '';
			app.SPButton = uibutton(app.GeneralPanel, 'push');
			app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
			app.SPButton.Icon = 'go-to-file.png';
			app.SPButton.Text = '';
			app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
			app.InfoEditFieldLabel.Text = 'Info';
			app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
			app.InfoEditField.Value = 'Behavioural Acuity Test';
			app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
			app.SubjectEditFieldLabel.Text = 'Subject';
			app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
			app.SubjectEditField.Value = 'DEBUG';
			app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
			app.SavePathEditFieldLabel.Text = 'Save Path';
			app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
			app.SavePathEditField.Value = '/home/aristotle/Desktop/';
			app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
			app.CalibrationEditFieldLabel.Text = 'Calibration';
			app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
			app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
			app.DistanceEditFieldLabel.Text = 'Distance';
			app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.DistanceEditField.Limits = [20 200];
			app.DistanceEditField.Value = 62.5;
			app.PixelsCmEditFieldLabel = uilabel(app.GeneralPanel);
			app.PixelsCmEditFieldLabel.Text = 'Pixels / Cm';
			app.PixelsCmEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.PixelsCmEditField.Limits = [5 300];
			app.PixelsCmEditField.Value = 36;
			app.DebugModeSwitchLabel = uilabel(app.GeneralPanel);
			app.DebugModeSwitchLabel.Text = 'Debug Mode';
			app.DebugModeSwitch = uiswitch(app.GeneralPanel, 'rocker');
			app.BackgroundColorEditFieldLabel = uilabel(app.GeneralPanel);
			app.BackgroundColorEditFieldLabel.Text = 'Background Color';
			app.BackgroundColorEditField = uieditfield(app.GeneralPanel, 'text');
			app.BackgroundColorEditField.Value = '0.25 0.25 0.25';
			app.TaskTypeListBoxLabel = uilabel(app.GeneralPanel);
			app.TaskTypeListBoxLabel.Text = 'Task Type';
			app.TaskTypeListBox = uilistbox(app.GeneralPanel);
			app.TaskTypeListBox.Items = {'Blank Stage 1', 'Blank Stage 2', 'Grating Alone', 'Blank + Grating'};
			app.TaskTypeListBox.ValueChangedFcn = createCallbackFcn(app, @TaskTypeListBoxValueChanged, true);
			app.TaskTypeListBox.Value = 'Blank + Grating';
			app.nBlocksEditFieldLabel = uilabel(app.GeneralPanel);
			app.nBlocksEditFieldLabel.Text = 'nBlocks';
			app.nBlocksEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.nBlocksEditField.Limits = [1 2000];
			app.nBlocksEditField.RoundFractionalValues = 'on';
			app.nBlocksEditField.Value = 10;
			app.IFIEditFieldLabel = uilabel(app.GeneralPanel);
			app.IFIEditFieldLabel.Text = 'IFI';
			app.IFIEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.IFIEditField.Limits = [0 1000];
			app.IFIEditField.Value = 1;
			app.PunishIFIEditFieldLabel = uilabel(app.GeneralPanel);
			app.PunishIFIEditFieldLabel.Text = 'Punish IFI';
			app.PunishIFIEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.PunishIFIEditField.Limits = [0 1000];
			app.PunishIFIEditField.Value = 3;
			app.OperatorEditFieldLabel = uilabel(app.GeneralPanel);
			app.OperatorEditFieldLabel.Text = 'Operator %';
			app.OperatorEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.OperatorEditField.Limits = [10 100];
			app.OperatorEditField.RoundFractionalValues = 'on';
			app.OperatorEditField.Tooltip = {'How much of the operator screen to use up?'};
			app.OperatorEditField.Value = 60;
			app.OperatorPxCmEditFieldLabel = uilabel(app.GeneralPanel);
			app.OperatorPxCmEditFieldLabel.Text = 'Operator Px/Cm';
			app.OperatorPxCmEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.OperatorPxCmEditField.Limits = [1 200];
			app.OperatorPxCmEditField.Tooltip = {'Use higher values to scale to operator screen'};
			app.OperatorPxCmEditField.Value = 18;
			app.ColorDepthDropDownLabel = uilabel(app.GeneralPanel);
			app.ColorDepthDropDownLabel.Text = 'Color Depth';
			app.ColorDepthDropDown = uidropdown(app.GeneralPanel);
			app.ColorDepthDropDown.Items = {'FloatingPoint32BitIfPossible', 'FloatingPoint32Bit', '8bit', 'EnableBits++Bits++Output', 'EnableBits++Color++Output', 'EnableBits++Mono++Output', 'Native10Bit', 'Native11Bit'};
			app.ColorDepthDropDown.Value = 'Native10Bit';
			app.TaskStimulusOptionsPanel = uipanel(app.ControlTab);
			app.TaskStimulusOptionsPanel.Title = 'Task / Stimulus Options';
			app.StairCaseSwitchLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.StairCaseSwitchLabel.Text = 'StairCase?';
			app.StairCaseSwitch = uiswitch(app.TaskStimulusOptionsPanel, 'slider');
			app.StairCaseSwitch.ValueChangedFcn = createCallbackFcn(app, @StairCaseSwitchValueChanged, true);
			app.StimulusSizeEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.StimulusSizeEditFieldLabel.Text = 'Stimulus Size';
			app.StimulusSizeEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.StimulusSizeEditField.Limits = [0 300];
			app.StimulusSizeEditField.Tooltip = {'BLANK / GRATING SIZE'};
			app.StimulusSizeEditField.Value = 10;
			app.TargetdegEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.TargetdegEditFieldLabel.Text = 'Target (deg)';
			app.TargetdegEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.TargetdegEditField.Limits = [-100 100];
			app.TargetdegEditField.Tooltip = {'TARGET POSITION'};
			app.TargetdegEditField.Value = -15;
			app.SigmaEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.SigmaEditFieldLabel.Text = 'Sigma';
			app.SigmaEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.SigmaEditField.Limits = [0 300];
			app.SigmaEditField.Tooltip = {'SMOOTHING of BLANK / GRATING'};
			app.SigmaEditField.Value = 51;
			app.PFDropDownLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.PFDropDownLabel.Text = 'PF';
			app.PFDropDown = uidropdown(app.TaskStimulusOptionsPanel);
			app.PFDropDown.Items = {'Weibull', 'Quick', 'Gumbel', 'logQuick', 'Logistic', 'CumulativeNormal', 'HyperbolicSecant'};
			app.PFDropDown.Editable = 'on';
			app.PFDropDown.Value = 'Quick';
			app.gammaEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.gammaEditFieldLabel.Text = 'gamma';
			app.gammaEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.gammaEditField.Limits = [0 1];
			app.gammaEditField.Value = 0.5;
			app.betamaxEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.betamaxEditFieldLabel.Text = 'beta max';
			app.betamaxEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.betamaxEditField.Limits = [0 2000];
			app.betamaxEditField.Value = 20;
			app.lambdaEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.lambdaEditFieldLabel.Text = 'lambda';
			app.lambdaEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.lambdaEditField.Limits = [0 200];
			app.lambdaEditField.Value = 0.01;
			app.ConstrastRangeEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.ConstrastRangeEditFieldLabel.Text = 'Constrast Range';
			app.ConstrastRangeEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.ConstrastRangeEditField.Tooltip = {'Set of contrasts to vary grating'};
			app.ConstrastRangeEditField.Value = '0 : 0.01 : 0.2';
			app.PriorsSwitchLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.PriorsSwitchLabel.Text = 'Priors?';
			app.PriorsSwitch = uiswitch(app.TaskStimulusOptionsPanel, 'slider');
			app.LogalphaCheckBox = uicheckbox(app.TaskStimulusOptionsPanel);
			app.LogalphaCheckBox.Text = 'Log alpha';
			app.alphaSDEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.alphaSDEditFieldLabel.Text = 'alpha SD';
			app.alphaSDEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.alphaSDEditField.Limits = [0 300];
			app.alphaSDEditField.Value = 0.15;
			app.alphapriorEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.alphapriorEditFieldLabel.Text = 'alpha prior';
			app.alphapriorEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.alphapriorEditField.Limits = [0 2000];
			app.alphapriorEditField.Value = 0.05;
			app.betaSDEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.betaSDEditFieldLabel.Text = 'beta SD';
			app.betaSDEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.betaSDEditField.Limits = [0 300];
			app.betaSDEditField.Value = 6;
			app.betapriorEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.betapriorEditFieldLabel.Text = 'beta prior';
			app.betapriorEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.betapriorEditField.Limits = [0 200];
			app.betapriorEditField.Value = 5;
			app.marginaliseEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.marginaliseEditFieldLabel.Text = 'marginalise';
			app.marginaliseEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.marginaliseEditField.Value = '''slope''';
			app.TestButton = uibutton(app.TaskStimulusOptionsPanel, 'push');
			app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
			app.TestButton.Text = 'Test';
			app.LogbetaCheckBox = uicheckbox(app.TaskStimulusOptionsPanel);
			app.LogbetaCheckBox.Text = 'Log beta';
			app.betagrainEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.betagrainEditFieldLabel.Text = 'beta grain';
			app.betagrainEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.betagrainEditField.Limits = [0 1000];
			app.betagrainEditField.Value = 50;
			app.alphagrainEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.alphagrainEditFieldLabel.Text = 'alpha grain';
			app.alphagrainEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.alphagrainEditField.Limits = [0 1000];
			app.alphagrainEditField.Value = 100;
			app.DiskColorEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.DiskColorEditFieldLabel.Text = 'Disk Color';
			app.DiskColorEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.DiskColorEditField.Value = '0.5 0.5 0.5';
			app.FixTargetsEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.FixTargetsEditFieldLabel.Text = 'Fix Target (s)';
			app.FixTargetsEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.FixTargetsEditField.Limits = [0 300];
			app.FixTargetsEditField.Tooltip = {'Time to FIX TARGET'};
			app.FixTargetsEditField.Value = 0.3;
			app.InitTargetsEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.InitTargetsEditFieldLabel.Text = 'Init Target (s)';
			app.InitTargetsEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.InitTargetsEditField.Limits = [0 100];
			app.InitTargetsEditField.Tooltip = {'Time to find TARGET'};
			app.InitTargetsEditField.Value = 0.6;
			app.TargetONsEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.TargetONsEditFieldLabel.Text = 'Target ON (s)';
			app.TargetONsEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.TargetONsEditField.Tooltip = {'TARGET ON MIN + MAX'};
			app.TargetONsEditField.Value = '0.2 0.5';
			app.FixOFFsEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.FixOFFsEditFieldLabel.Text = 'Fix OFF (s)';
			app.FixOFFsEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.FixOFFsEditField.Tooltip = {'Time for fix cross OFF in BLANK'};
			app.FixOFFsEditField.Placeholder = '0.8';
			app.FixOFFsEditField.Value = '0.25';
			app.SwitchtimesEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.SwitchtimesEditFieldLabel.Text = 'Switch time (s)';
			app.SwitchtimesEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.SwitchtimesEditField.Tooltip = {'Switch time MIN + MAX'};
			app.SwitchtimesEditField.Value = '0.5 1.25';
			app.FadeFrameEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.FadeFrameEditFieldLabel.Text = 'Fade/Frame %';
			app.FadeFrameEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.FadeFrameEditField.Tooltip = {'PER FRAME REDUCTION BLANK'};
			app.FadeFrameEditField.Value = '5';
			app.alphaEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.alphaEditField.Limits = [0 10];
			app.alphaEditField.Tooltip = {'Time for fix cross OFF in GRATING'};
			app.alphaEditField.Value = 0.1;
			app.GratingEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.GratingEditFieldLabel.Text = '% Grating';
			app.GratingEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.GratingEditField.Limits = [0 100];
			app.GratingEditField.RoundFractionalValues = 'on';
			app.GratingEditField.Tooltip = {'PROBABILITY of GRATING TRIAL'};
			app.GratingEditField.Value = 75;
			app.KeepBlanksEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.KeepBlanksEditFieldLabel.Text = 'Keep Blank (s)';
			app.KeepBlanksEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.KeepBlanksEditField.Limits = [0 100];
			app.KeepBlanksEditField.Tooltip = {'TIME to keep BLANK after the switch...'; ''};
			app.KeepBlanksEditField.Value = 0.7;
			app.FadeFinalEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.FadeFinalEditField.Tooltip = {'MINIMUM OPACITY %'};
			app.FadeFinalEditField.Value = '0';
			app.stopruleEditFieldLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.stopruleEditFieldLabel.Text = 'stop rule';
			app.stopruleEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.stopruleEditField.Limits = [1 2000];
			app.stopruleEditField.RoundFractionalValues = 'on';
			app.stopruleEditField.Value = 40;
			app.FadeFrameTargetEditField = uieditfield(app.TaskStimulusOptionsPanel, 'text');
			app.FadeFrameTargetEditField.Tooltip = {'PER FRAME REDUCTION TARGET'};
			app.FadeFrameTargetEditField.Value = '5';
			app.SFLabel = uilabel(app.TaskStimulusOptionsPanel);
			app.SFLabel.Text = 'SF';
			app.SFEditField = uieditfield(app.TaskStimulusOptionsPanel, 'numeric');
			app.SFEditField.Limits = [0 100];
			app.SFEditField.RoundFractionalValues = 'on';
			app.SFEditField.Tooltip = {'PROBABILITY of GRATING TRIAL'};
			app.SFEditField.Value = 1;
			app.RunPanel = uipanel(app.ControlTab);
			app.RunPanel.Title = 'Run';
			app.SaveButton = uibutton(app.RunPanel, 'push');
			app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
			app.SaveButton.Enable = 'off';
			app.SaveButton.Text = 'Save!';
			app.ExitButton = uibutton(app.RunPanel, 'push');
			app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
			app.ExitButton.Icon = 'stop.png';
			app.ExitButton.Text = 'Exit!';
			app.GOButton = uibutton(app.RunPanel, 'push');
			app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
			app.GOButton.Icon = 'start.png';
			app.GOButton.Text = 'GO!';
			app.EyeTrackerTab = uitab(app.TabGroup);
			app.EyeTrackerTab.Title = 'EyeTracker';
			app.EyetrackersettingsPanel = uipanel(app.EyeTrackerTab);
			app.EyetrackersettingsPanel.Title = 'Eyetracker settings';
			app.TrackerDropDownLabel = uilabel(app.EyetrackersettingsPanel);
			app.TrackerDropDownLabel.Text = 'Tracker';
			app.TrackerDropDown = uidropdown(app.EyetrackersettingsPanel);
			app.TrackerDropDown.Items = {'Tobii Pro Spectrum', 'Tobii TX300', 'Tobii 4C'};
			app.TrackerDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackerDropDownValueChanged, true);
			app.TrackerDropDown.Value = 'Tobii Pro Spectrum';
			app.TrackingmodeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
			app.TrackingmodeDropDownLabel.Text = 'Tracking mode';
			app.TrackingmodeDropDown = uidropdown(app.EyetrackersettingsPanel);
			app.TrackingmodeDropDown.Items = {'human', 'macaque'};
			app.TrackingmodeDropDown.Value = 'human';
			app.CalibrationstimulusDropDownLabel = uilabel(app.EyetrackersettingsPanel);
			app.CalibrationstimulusDropDownLabel.Text = 'Calibration stimulus';
			app.CalibrationstimulusDropDown = uidropdown(app.EyetrackersettingsPanel);
			app.CalibrationstimulusDropDown.Items = {'animated', 'movie', 'normal'};
			app.CalibrationstimulusDropDown.Value = 'animated';
			app.EyeusedDropDownLabel = uilabel(app.EyetrackersettingsPanel);
			app.EyeusedDropDownLabel.Text = 'Eye used';
			app.EyeusedDropDown = uidropdown(app.EyetrackersettingsPanel);
			app.EyeusedDropDown.Items = {'both', 'left', 'right'};
			app.EyeusedDropDown.Value = 'both';
			app.UseEyeTrackerCheckBox = uicheckbox(app.EyetrackersettingsPanel);
			app.UseEyeTrackerCheckBox.Enable = 'off';
			app.UseEyeTrackerCheckBox.Text = 'Use Eye Tracker?';
			app.UseEyeTrackerCheckBox.Value = true;
			app.DummyModeCheckBox = uicheckbox(app.EyetrackersettingsPanel);
			app.DummyModeCheckBox.Text = 'Dummy Mode?';
			app.SamplerateKnobLabel = uilabel(app.EyetrackersettingsPanel);
			app.SamplerateKnobLabel.Text = 'Sample rate';
			app.SamplerateKnob = uiknob(app.EyetrackersettingsPanel, 'discrete');
			app.SamplerateKnob.Items = {'60', '90', '120', '150', '300', '600', '1200'};
			app.SamplerateKnob.ValueChangedFcn = createCallbackFcn(app, @SamplerateKnobValueChanged, true);
			app.SamplerateKnob.Value = '300';
			app.DrawEyeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
			app.DrawEyeDropDownLabel.Text = 'Draw Eye?';
			app.DrawEyeDropDown = uidropdown(app.EyetrackersettingsPanel);
			app.DrawEyeDropDown.Items = {'No', 'On screen', 'New Screen'};
			app.DrawEyeDropDown.Value = 'New Screen';
			app.InitialFixationWindowPanel = uipanel(app.EyetrackersettingsPanel);
			app.InitialFixationWindowPanel.Title = 'Initial Fixation Window';
			app.INITEditField = uieditfield(app.InitialFixationWindowPanel, 'numeric');
			app.INITEditField.Limits = [0 100];
			app.INITEditField.Tooltip = {'Time to initiate fixation'};
			app.INITEditField.Value = 3;
			app.INITEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.INITEditFieldLabel.Text = 'INIT';
			app.FIXEditField = uieditfield(app.InitialFixationWindowPanel, 'numeric');
			app.FIXEditField.Limits = [0 100];
			app.FIXEditField.Tooltip = {'Minimum time to stay fixated'};
			app.FIXEditField.Value = 0.3;
			app.FIXEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.FIXEditFieldLabel.Text = 'FIX';
			app.XEditField = uieditfield(app.InitialFixationWindowPanel, 'numeric');
			app.XEditField.Limits = [-100 100];
			app.XEditField.Tooltip = {'X position in degrees from the center of the screen'};
			app.XEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.XEditFieldLabel.Text = 'X';
			app.YEditField = uieditfield(app.InitialFixationWindowPanel, 'numeric');
			app.YEditField.Limits = [-100 100];
			app.YEditField.Tooltip = {'Y position in degrees from the center of the screen'};
			app.YEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.YEditFieldLabel.Text = 'Y';
			app.RadiusEditField = uieditfield(app.InitialFixationWindowPanel, 'text');
			app.RadiusEditField.Tooltip = {'Radius of fixation window'};
			app.RadiusEditField.Value = '2';
			app.RADIUSEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.RADIUSEditFieldLabel.Text = 'RADIUS';
			app.STRICTEditField = uieditfield(app.InitialFixationWindowPanel, 'numeric');
			app.STRICTEditField.Limits = [0 100];
			app.STRICTEditField.Tooltip = {'Minimum time to stay fixated'};
			app.STRICTEditField.Value = 1;
			app.STRICTEditFieldLabel = uilabel(app.InitialFixationWindowPanel);
			app.STRICTEditFieldLabel.Text = 'STRICT';
			app.FixationCrossParametersPanel = uipanel(app.EyetrackersettingsPanel);
			app.FixationCrossParametersPanel.Title = 'Fixation Cross Parameters';
			app.SpotSizeEditFieldLabel = uilabel(app.FixationCrossParametersPanel);
			app.SpotSizeEditFieldLabel.Text = 'Spot Size';
			app.SpotSizeEditField = uieditfield(app.FixationCrossParametersPanel, 'numeric');
			app.SpotSizeEditField.Limits = [0 100];
			app.SpotSizeEditField.Value = 1.25;
			app.SpotAlphaEditFieldLabel = uilabel(app.FixationCrossParametersPanel);
			app.SpotAlphaEditFieldLabel.Text = 'Spot Alpha';
			app.SpotAlphaEditField = uieditfield(app.FixationCrossParametersPanel, 'numeric');
			app.SpotAlphaEditField.Limits = [0 1];
			app.SpotAlphaEditField.Value = 0.8;
			app.SpotLineEditFieldLabel = uilabel(app.FixationCrossParametersPanel);
			app.SpotLineEditFieldLabel.Text = 'Spot Line';
			app.SpotLineEditField = uieditfield(app.FixationCrossParametersPanel, 'numeric');
			app.SpotLineEditField.Limits = [0 100];
			app.SpotLineEditField.Value = 0.15;
			app.GraceTimeEditFieldLabel = uilabel(app.FixationCrossParametersPanel);
			app.GraceTimeEditFieldLabel.Enable = 'off';
			app.GraceTimeEditFieldLabel.Text = 'GraceTime';
			app.GraceTimeEditField = uieditfield(app.FixationCrossParametersPanel, 'numeric');
			app.GraceTimeEditField.Limits = [0 100];
			app.GraceTimeEditField.RoundFractionalValues = 'on';
			app.GraceTimeEditField.ValueDisplayFormat = '%.0f';
			app.GraceTimeEditField.Enable = 'off';
			app.GraceTimeEditField.Tooltip = {'The time the monkey has to move their eyes before the fixation window is enforced suring stimulus presentation'; ''};
			app.CalibrationSmoothingPanel = uipanel(app.EyetrackersettingsPanel);
			app.CalibrationSmoothingPanel.Title = 'Calibration / Smoothing';
			app.msLabel = uilabel(app.CalibrationSmoothingPanel);
			app.msLabel.Text = '20ms';
			app.CalEditFieldLabel = uilabel(app.CalibrationSmoothingPanel);
			app.CalEditFieldLabel.Text = 'Cal';
			app.CalEditField = uieditfield(app.CalibrationSmoothingPanel, 'text');
			app.CalEditField.Value = '[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]';
			app.ValEditFieldLabel = uilabel(app.CalibrationSmoothingPanel);
			app.ValEditFieldLabel.Text = 'Val';
			app.ValEditField = uieditfield(app.CalibrationSmoothingPanel, 'text');
			app.ValEditField.Value = '[ 0.5 0.5 ]';
			app.AutoPaceCheckBox = uicheckbox(app.CalibrationSmoothingPanel);
			app.AutoPaceCheckBox.Text = 'Auto Pace';
			app.eyeCALButton = uibutton(app.CalibrationSmoothingPanel, 'push');
			app.eyeCALButton.ButtonPushedFcn = createCallbackFcn(app, @eyeCALButtonPushed, true);
			app.eyeCALButton.Icon = 'go-to-file.png';
			app.eyeCALButton.Text = '';
			app.CalibrationEditField_2Label = uilabel(app.CalibrationSmoothingPanel);
			app.CalibrationEditField_2Label.Text = 'Calibration';
			app.CalibrationEditField_2 = uieditfield(app.CalibrationSmoothingPanel, 'text');
			app.PaceDurationEditField = uieditfield(app.CalibrationSmoothingPanel, 'numeric');
			app.PaceDurationEditField.Tooltip = {'Pace duration (s)'};
			app.PaceDurationEditField.Value = 0.8;
			app.ReloadPreviousCheckBox = uicheckbox(app.CalibrationSmoothingPanel);
			app.ReloadPreviousCheckBox.Tooltip = {'Reload previous calibration file automatically?'};
			app.ReloadPreviousCheckBox.Text = 'Reload Previous?';
			app.ManualCheckBox = uicheckbox(app.CalibrationSmoothingPanel);
			app.ManualCheckBox.Text = 'Manual';
			app.ManualCheckBox.Value = true;
			app.EyeDropDownLabel = uilabel(app.CalibrationSmoothingPanel);
			app.EyeDropDownLabel.Text = {'Eye'; ''};
			app.smoothEyeDropDown = uidropdown(app.CalibrationSmoothingPanel);
			app.smoothEyeDropDown.Items = {'both', 'left', 'right'};
			app.smoothEyeDropDown.Tooltip = {'smoothing use eye data?'};
			app.smoothEyeDropDown.Value = 'both';
			app.MethodDropDownLabel = uilabel(app.CalibrationSmoothingPanel);
			app.MethodDropDownLabel.Text = 'Method';
			app.smoothMethodDropDown = uidropdown(app.CalibrationSmoothingPanel);
			app.smoothMethodDropDown.Items = {'median', 'heuristic1', 'heuristic2', 'savitsky-golay', 'basic'};
			app.smoothMethodDropDown.Tooltip = {'smoothing method'};
			app.smoothMethodDropDown.Value = 'median';
			app.wEditFieldLabel = uilabel(app.CalibrationSmoothingPanel);
			app.wEditFieldLabel.Text = 'w';
			app.windowEditField = uieditfield(app.CalibrationSmoothingPanel, 'numeric');
			app.windowEditField.Limits = [1 100];
			app.windowEditField.RoundFractionalValues = 'on';
			app.windowEditField.ValueDisplayFormat = '%.0f';
			app.windowEditField.Tooltip = {'smoothing window'};
			app.windowEditField.Value = 3;
			app.EditFieldLabel = uilabel(app.CalibrationSmoothingPanel);
			app.EditFieldLabel.Text = '#';
			app.nSamplesEditField = uieditfield(app.CalibrationSmoothingPanel, 'numeric');
			app.nSamplesEditField.Limits = [1 200];
			app.nSamplesEditField.RoundFractionalValues = 'on';
			app.nSamplesEditField.ValueDisplayFormat = '%.0f';
			app.nSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nSamplesEditFieldValueChanged, true);
			app.nSamplesEditField.Tooltip = {'# of samples to smooth'};
			app.nSamplesEditField.Value = 4;
			app.CalOptionsTextAreaLabel = uilabel(app.CalibrationSmoothingPanel);
			app.CalOptionsTextAreaLabel.Text = 'Cal Options';
			app.CalOptionsTextArea = uitextarea(app.CalibrationSmoothingPanel);
			app.CalOptionsTextArea.Value = {'[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]'; ''; '[ 0.5 0.5 ]'; ''; '[ 0.2 0.5; 0.5 0.2; 0.5 0.5; 0.8 0.5; 0.5 0.8 ]'; ''; ''};
			app.PlotsTab = uitab(app.TabGroup);
			app.PlotsTab.Title = 'Plots';
			app.Data1Axes = uiaxes(app.PlotsTab);
			title(app.Data1Axes, 'Raw Data')
			xlabel(app.Data1Axes, 'Trial')
			ylabel(app.Data1Axes, 'Stimulus Value')
			app.Data2Axes = uiaxes(app.PlotsTab);
			title(app.Data2Axes, 'PF')
			xlabel(app.Data2Axes, 'Stimulus Value')
			ylabel(app.Data2Axes, 'Choice Probability')
			app.Data4Axes = uiaxes(app.PlotsTab);
			title(app.Data4Axes, 'Posterior')
			xlabel(app.Data4Axes, 'beta')
			ylabel(app.Data4Axes, 'alpha')
			app.Data3Axes = uiaxes(app.PlotsTab);
			title(app.Data3Axes, 'Posterior')
			xlabel(app.Data3Axes, 'beta')
			ylabel(app.Data3Axes, 'alpha')
			% Show the figure after all components are created
			app.BehaviouralAcuityTestsUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = behaviouralAcuity

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.BehaviouralAcuityTestsUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.BehaviouralAcuityTestsUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.BehaviouralAcuityTestsUIFigure)
		end
	end
end