classdef loadEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VEPAnalysisUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DataEditFieldLabel           matlab.ui.control.Label
        DataEditField                matlab.ui.control.EditField
        TriggerEditFieldLabel        matlab.ui.control.Label
        TriggerEditField             matlab.ui.control.EditField
        LoadButton                   matlab.ui.control.Button
        EDFFile                      matlab.ui.control.TextArea
        ShowTriggersCheckBox         matlab.ui.control.CheckBox
        MATFile                      matlab.ui.control.TextArea
        RUNButton                    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        MinmsEditFieldLabel          matlab.ui.control.Label
        MinmsEditField               matlab.ui.control.NumericEditField
        JittermsEditFieldLabel       matlab.ui.control.Label
        JittermsEditField            matlab.ui.control.NumericEditField
        PadsEditFieldLabel           matlab.ui.control.Label
        PadsEditField                matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.EditField
        PreprocessingPanel           matlab.ui.container.Panel
        DFTFilterSwitchLabel         matlab.ui.control.Label
        DFTFilterSwitch              matlab.ui.control.Switch
        DemeanSwitchLabel            matlab.ui.control.Label
        DemeanSwitch                 matlab.ui.control.Switch
        DetrendSwitchLabel           matlab.ui.control.Label
        DetrendSwitch                matlab.ui.control.Switch
        BaselineEditFieldLabel       matlab.ui.control.Label
        BaselineEditField            matlab.ui.control.EditField
        RejectCheckBox               matlab.ui.control.CheckBox
        rejectTypeDropDown           matlab.ui.control.DropDown
        RereferenceEditFieldLabel    matlab.ui.control.Label
        RereferenceEditField         matlab.ui.control.EditField
        MakeSurrogateCheckBox        matlab.ui.control.CheckBox
        surrogateChannel             matlab.ui.control.NumericEditField
        LowpassEditFieldLabel        matlab.ui.control.Label
        LowpassEditField             matlab.ui.control.NumericEditField
        HighpassEditFieldLabel       matlab.ui.control.Label
        HighpassEditField            matlab.ui.control.NumericEditField
        filtertypeDropDown           matlab.ui.control.DropDown
        MedianSwitchLabel            matlab.ui.control.Label
        MedianSwitch                 matlab.ui.control.Switch
        PolyRemovalSwitchLabel       matlab.ui.control.Label
        PolyRemovalSwitch            matlab.ui.control.Switch
        BandpassCheckBox             matlab.ui.control.CheckBox
        OrderEditFieldLabel          matlab.ui.control.Label
        OrderEditField               matlab.ui.control.NumericEditField
        dirDropDown                  matlab.ui.control.DropDown
        rerefDropDown                matlab.ui.control.DropDown
        ShowFilterCheckBox           matlab.ui.control.CheckBox
        RejectTrialsEditField        matlab.ui.control.EditField
        TimelockPanel                matlab.ui.container.Panel
        KeepTrialsCheckBox           matlab.ui.control.CheckBox
        PlotRangeEditFieldLabel      matlab.ui.control.Label
        PlotRangeEditField           matlab.ui.control.EditField
        CovarianceCheckBox           matlab.ui.control.CheckBox
        RemovemeanCheckBox           matlab.ui.control.CheckBox
        AnalRangeEditFieldLabel      matlab.ui.control.Label
        AnalRangeEditField           matlab.ui.control.EditField
        doTimelockCheckBox           matlab.ui.control.CheckBox
        ChannelsEditFieldLabel       matlab.ui.control.Label
        ChannelsEditField            matlab.ui.control.EditField
        TimefrequencyPanel           matlab.ui.container.Panel
        FreqRangeEditFieldLabel      matlab.ui.control.Label
        FreqRangeEditField           matlab.ui.control.EditField
        TaperDropDownLabel           matlab.ui.control.Label
        TaperDropDown                matlab.ui.control.DropDown
        EditFieldLabel               matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        BaselineDropDownLabel        matlab.ui.control.Label
        BaselineDropDown             matlab.ui.control.DropDown
        doTimeFreqCheckBox           matlab.ui.control.CheckBox
        pDiodeLabel                  matlab.ui.control.Label
        pDiodeEditField              matlab.ui.control.EditField
        CorrectLabel                 matlab.ui.control.Label
        CorrectEditField             matlab.ui.control.NumericEditField
        TriggersOKLampLabel          matlab.ui.control.Label
        TriggersOKLamp               matlab.ui.control.Lamp
        versionLabel                 matlab.ui.control.Label
        FastFourierAnalysisPanel     matlab.ui.container.Panel
        AvgDropDownLabel             matlab.ui.control.Label
        AvgDropDown                  matlab.ui.control.DropDown
        ErrorDropDownLabel           matlab.ui.control.Label
        ErrorDropDown                matlab.ui.control.DropDown
        pvalueEditFieldLabel         matlab.ui.control.Label
        pvalueEditField              matlab.ui.control.NumericEditField
        WindowedCheckBox             matlab.ui.control.CheckBox
        CombinePlotsCheckBox         matlab.ui.control.CheckBox
        LinearAxisCheckBox           matlab.ui.control.CheckBox
        BlankCheckBox                matlab.ui.control.CheckBox
        excludePointsEditField       matlab.ui.control.EditField
        PointstoExcludefromFitLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
    end

 
 properties (Access = private)
   ana % data to send for analysis
   version = '1.07' % Description
 end
 
 methods (Access = private)
  
  function getValues(app)
   app.ana = [];
   app.ana.EDFFile = app.EDFFile.Value;
   app.ana.MATFile = app.MATFile.Value;
   if iscell(app.ana.EDFFile); app.ana.EDFFile = app.ana.EDFFile{1}; end
   if iscell(app.ana.MATFile); app.ana.MATFile = app.ana.MATFile{1}; end
   app.ana.plotTriggers = logical(app.ShowTriggersCheckBox.Value);
   app.ana.minTrigger = app.MinmsEditField.Value;
   app.ana.jitter = app.JittermsEditField.Value;
   app.ana.correctID = app.CorrectEditField.Value;
   app.ana.preTime = app.PadsEditField.Value;
   app.ana.threshold = app.ThresholdEditField.Value;
   app.ana.dataChannels = str2num(app.DataEditField.Value);
   app.ana.bitChannels = str2num(app.TriggerEditField.Value);
   app.ana.pDiode = str2num(app.pDiodeEditField.Value);
   
   app.ana.dftfilter = app.toYesNo(app.DFTFilterSwitch.Value);
   app.ana.demean = app.toYesNo(app.DemeanSwitch.Value);
   app.ana.medianfilter = app.toYesNo(app.MedianSwitch.Value);
   app.ana.detrend = app.toYesNo(app.DetrendSwitch.Value);
   app.ana.polyremoval = app.toYesNo(app.PolyRemovalSwitch.Value);
   app.ana.rejectvisual = app.RejectCheckBox.Value;
   app.ana.rejecttype = app.rejectTypeDropDown.Value;
   app.ana.rejecttrials = str2num(app.RejectTrialsEditField.Value);
   app.ana.rjHandle		= app.RejectTrialsEditField;
   app.ana.rereference = str2num(app.RereferenceEditField.Value);
   app.ana.rerefMethod = app.rerefDropDown.Value;
   app.ana.baseline = str2num(app.BaselineEditField.Value);
   app.ana.makeSurrogate = app.MakeSurrogateCheckBox.Value;
   app.ana.surrogateChannel = app.surrogateChannel.Value;
   app.ana.lowpass = app.LowpassEditField.Value;
   app.ana.highpass = app.HighpassEditField.Value;
   app.ana.order = app.OrderEditField.Value;
   app.ana.filtertype = app.filtertypeDropDown.Value;
   app.ana.direction = app.dirDropDown.Value;
   app.ana.bandpass = app.BandpassCheckBox.Value;
   app.ana.plotFilter = app.ShowFilterCheckBox.Value;
   app.ana.doTimelock = app.doTimelockCheckBox.Value;
   app.ana.doTimeFreq = app.doTimeFreqCheckBox.Value;
   
   app.ana.tlremovemean = app.toYesNo(app.RemovemeanCheckBox.Value);
   app.ana.tlcovariance = app.toYesNo(app.CovarianceCheckBox.Value);
   val = app.PlotRangeEditField.Value;
   if contains(val,'all','IgnoreCase',true)
	   app.ana.plotRange = 'all';
   else
	   app.ana.plotRange = str2num(val);
   end
   val = app.ChannelsEditField.Value;
   if isempty(val)
	   app.ana.tlChannels = app.ana.dataChannels;
   else
	   app.ana.tlChannels = str2num(val);
   end
   app.ana.analRange = str2num(app.AnalRangeEditField.Value);
   app.ana.tlkeeptrials = app.KeepTrialsCheckBox.Value;
   app.ana.avgmethod = app.AvgDropDown.Value;
   app.ana.errormethod = app.ErrorDropDown.Value;
   app.ana.pvalue = app.pvalueEditField.Value;
   app.ana.fftwindow = app.WindowedCheckBox.Value;
   app.ana.combineFFT = app.CombinePlotsCheckBox.Value;
   app.ana.linearAxis = app.LinearAxisCheckBox.Value;
   app.ana.removeBlank = app.BlankCheckBox.Value;
   app.ana.excludePoints = app.excludePointsEditField.Value;
   
   app.ana.freqrange = str2num(app.FreqRangeEditField.Value);
   app.ana.freqtaper = app.TaperDropDown.Value;
   app.ana.freqbaseline = app.BaselineDropDown.Value;
   app.ana.freqbaselinevalue = str2num(app.EditField.Value);
   
   app.ana.table = app.UITable;
   app.ana.warning = app.TriggersOKLamp;
   app.ana.versionLabel = app.versionLabel;
  end
  
  function results = toYesNo(~,in)
   if isnumeric(in)
    in = logical(in);
   elseif ischar(in)
    switch lower(in)
		case {'on','start','yes'}
      in = true;
     case {'off','stop','no'}
      in = false;
    end
   end
   if in
    results = 'yes';
   else
    results = 'no';
   end
  end
 end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            r = groot;
            app.VEPAnalysisUIFigure.Position(1) = r.ScreenSize(3) - app.VEPAnalysisUIFigure.Position(3);
            app.VEPAnalysisUIFigure.Position(2) = r.ScreenSize(4) - app.VEPAnalysisUIFigure.Position(4);
			app.versionLabel.UserData = ['UI: V' app.version];
			app.versionLabel.Text = ['UI: V' app.version];
			ft_defaults;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           [f,p] = uigetfile("*.edf",'Load EDF File');
           if ischar(f)
			cd(p);
            app.EDFFile.Value = [p f];
			app.RejectTrialsEditField.Value = '';
           end
           [f,p] = uigetfile("*.mat",'Load Associated MAT File');
           if ischar(f)
            app.MATFile.Value = [p f];
           end
           app.UITable.Data = [];
		   app.TriggersOKLamp.Color = [0.5 0.5 0.5];
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
           getValues(app);
		   app.TriggersOKLamp.Color = [0.5 0.5 0.5];
           app.ana.doAnalysis = event.Source.Text;
           runEEGAnalysis(app.ana);
        end

        % Value changed function: ShowTriggersCheckBox
        function ShowTriggersCheckBoxValueChanged(app, event)
            value = app.ShowTriggersCheckBox.Value;
            if value
				app.doTimelockCheckBox.Value = false;
				app.doTimeFreqCheckBox.Value = false;
			elseif ~value && ~app.doTimelockCheckBox.Value && ~app.doTimeFreqCheckBox.Value
				app.doTimelockCheckBox.Value = true;
			end
        end

        % Value changed function: doTimelockCheckBox
        function doTimelockCheckBoxValueChanged(app, event)
            value = app.doTimelockCheckBox.Value;
            if value == true
				app.ShowTriggersCheckBox.Value = false;
			end
        end

        % Value changed function: doTimeFreqCheckBox
        function doTimeFreqCheckBoxValueChanged(app, event)
            value = app.doTimeFreqCheckBox.Value;
            if value == true
				app.ShowTriggersCheckBox.Value = false;
			end
        end

        % Value changed function: DataEditField
        function DataEditFieldValueChanged(app, event)
            %value = app.DataEditField.Value;
        end

        % Value changed function: pDiodeEditField
        function pDiodeEditFieldValueChanged(app, event)
            value = str2num(app.pDiodeEditField.Value);
			app.TriggerEditField.Value = [num2str(value-8) ':' num2str(value-1) ];
			if value-9 < 2
				app.DataEditField.Value = '1';
			else
				app.DataEditField.Value = ['1:' num2str(value-9)];
			end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            edffile = app.EDFFile.Value{1};
			if exist(edffile,'File')
				header = ft_read_header(edffile);
				if isfield(header,'orig') && iscell(header.orig)
					header = header.orig{1};
				end
				disp('=========Header INFO=========')
				disp(header);
				disp(header.label);
				assignin('base',"header",header);
				nchan = header.nChans;
				app.pDiodeEditField.Value = num2str(nchan);
				app.TriggerEditField.Value = [num2str(nchan-8) ':' num2str(nchan-1) ];
				if nchan-9 < 2
					app.DataEditField.Value = '1';
				else
					app.DataEditField.Value = ['1:' num2str(nchan-9)];
				end
			end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VEPAnalysisUIFigure = uifigure('Visible', 'off');
            app.VEPAnalysisUIFigure.Name = 'VEP Analysis';
            app.GridLayout = uigridlayout(app.VEPAnalysisUIFigure);
            app.DataEditFieldLabel = uilabel(app.GridLayout);
            app.DataEditFieldLabel.Text = {'Data #'; ''};
            app.DataEditField = uieditfield(app.GridLayout, 'text');
            app.DataEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.DataEditField.Value = '1:2';
            app.TriggerEditFieldLabel = uilabel(app.GridLayout);
            app.TriggerEditFieldLabel.Text = 'Trigger #';
            app.TriggerEditField = uieditfield(app.GridLayout, 'text');
            app.TriggerEditField.Value = '3:10';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EDFFile = uitextarea(app.GridLayout);
            app.EDFFile.Tooltip = {'EDF File to use'};
            app.ShowTriggersCheckBox = uicheckbox(app.GridLayout);
            app.ShowTriggersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTriggersCheckBoxValueChanged, true);
            app.ShowTriggersCheckBox.Text = 'Show Triggers?';
            app.MATFile = uitextarea(app.GridLayout);
            app.MATFile.Tooltip = {'MAT file to use'};
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'start.png';
            app.RUNButton.Text = 'RUN!';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Triggers Sent'; 'Triggers Received'; 'Stimuli'; 'Number'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true false];
            app.MinmsEditFieldLabel = uilabel(app.GridLayout);
            app.MinmsEditFieldLabel.Text = 'Min (ms)';
            app.MinmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinmsEditField.Limits = [1 100];
            app.MinmsEditField.RoundFractionalValues = 'on';
            app.MinmsEditField.Value = 15;
            app.JittermsEditFieldLabel = uilabel(app.GridLayout);
            app.JittermsEditFieldLabel.Text = 'Jitter (ms)';
            app.JittermsEditField = uieditfield(app.GridLayout, 'numeric');
            app.JittermsEditField.Limits = [1 100];
            app.JittermsEditField.RoundFractionalValues = 'on';
            app.JittermsEditField.Value = 3;
            app.PadsEditFieldLabel = uilabel(app.GridLayout);
            app.PadsEditFieldLabel.Text = 'Pad (s)';
            app.PadsEditField = uieditfield(app.GridLayout, 'numeric');
            app.PadsEditField.Limits = [0 100];
            app.PadsEditField.Value = 0.5;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout, 'text');
            app.ThresholdEditField.Value = '5 * nanmedian';
            app.PreprocessingPanel = uipanel(app.GridLayout);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.DFTFilterSwitchLabel = uilabel(app.PreprocessingPanel);
            app.DFTFilterSwitchLabel.Text = 'DFT Filter?';
            app.DFTFilterSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.DemeanSwitchLabel = uilabel(app.PreprocessingPanel);
            app.DemeanSwitchLabel.Text = 'Demean?';
            app.DemeanSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.DemeanSwitch.Value = 'On';
            app.DetrendSwitchLabel = uilabel(app.PreprocessingPanel);
            app.DetrendSwitchLabel.Text = {'Detrend?'; ''};
            app.DetrendSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.DetrendSwitch.Value = 'On';
            app.BaselineEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.PreprocessingPanel, 'text');
            app.BaselineEditField.Value = '-0.15 0';
            app.RejectCheckBox = uicheckbox(app.PreprocessingPanel);
            app.RejectCheckBox.Tooltip = {'Run a visual reject?'};
            app.RejectCheckBox.Text = 'Reject?';
            app.RejectCheckBox.Value = true;
            app.rejectTypeDropDown = uidropdown(app.PreprocessingPanel);
            app.rejectTypeDropDown.Items = {'summary', 'trial', 'channel'};
            app.rejectTypeDropDown.Tooltip = {'Visual method to use'};
            app.rejectTypeDropDown.Value = 'summary';
            app.RereferenceEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.RereferenceEditFieldLabel.Text = 'Re-reference?';
            app.RereferenceEditField = uieditfield(app.PreprocessingPanel, 'text');
            app.RereferenceEditField.Value = '0';
            app.MakeSurrogateCheckBox = uicheckbox(app.PreprocessingPanel);
            app.MakeSurrogateCheckBox.Text = 'Make Surrogate?';
            app.surrogateChannel = uieditfield(app.PreprocessingPanel, 'numeric');
            app.surrogateChannel.Limits = [1 Inf];
            app.surrogateChannel.Value = 1;
            app.LowpassEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.LowpassEditFieldLabel.Text = 'Lowpass:';
            app.LowpassEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.LowpassEditField.Limits = [0 1000];
            app.LowpassEditField.Value = 100;
            app.HighpassEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.HighpassEditFieldLabel.Text = 'Highpass:';
            app.HighpassEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.HighpassEditField.Limits = [0 1000];
            app.HighpassEditField.Value = 2;
            app.filtertypeDropDown = uidropdown(app.PreprocessingPanel);
            app.filtertypeDropDown.Items = {'but', 'firws', 'fir', 'firls', 'brickwall'};
            app.filtertypeDropDown.Tooltip = {'Filter type'};
            app.filtertypeDropDown.Value = 'but';
            app.MedianSwitchLabel = uilabel(app.PreprocessingPanel);
            app.MedianSwitchLabel.Text = {'Median?'; ''};
            app.MedianSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.PolyRemovalSwitchLabel = uilabel(app.PreprocessingPanel);
            app.PolyRemovalSwitchLabel.Text = 'Poly Removal?';
            app.PolyRemovalSwitch = uiswitch(app.PreprocessingPanel, 'slider');
            app.PolyRemovalSwitch.Value = 'On';
            app.BandpassCheckBox = uicheckbox(app.PreprocessingPanel);
            app.BandpassCheckBox.Text = 'Bandpass?';
            app.BandpassCheckBox.Value = true;
            app.OrderEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.OrderEditFieldLabel.Text = 'Order:';
            app.OrderEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.OrderEditField.Limits = [0 1000];
            app.OrderEditField.Tooltip = {'Filter order, 0 means use default values'};
            app.dirDropDown = uidropdown(app.PreprocessingPanel);
            app.dirDropDown.Items = {'twopass', 'onepass', 'twopass-reverse', 'onepass-reverse', 'onepass-zerophase'};
            app.dirDropDown.Tooltip = {'Filter type'};
            app.dirDropDown.Value = 'twopass';
            app.rerefDropDown = uidropdown(app.PreprocessingPanel);
            app.rerefDropDown.Items = {'avg', 'median', 'rest', 'bipolar'};
            app.rerefDropDown.Tooltip = {'Filter type'};
            app.rerefDropDown.Value = 'avg';
            app.ShowFilterCheckBox = uicheckbox(app.PreprocessingPanel);
            app.ShowFilterCheckBox.Text = 'Show Filter?';
            app.RejectTrialsEditField = uieditfield(app.PreprocessingPanel, 'text');
            app.RejectTrialsEditField.Tooltip = {'Trials to exclude...'};
            app.TimelockPanel = uipanel(app.GridLayout);
            app.TimelockPanel.Title = 'Time lock';
            app.KeepTrialsCheckBox = uicheckbox(app.TimelockPanel);
            app.KeepTrialsCheckBox.Text = 'Keep Trials?';
            app.PlotRangeEditFieldLabel = uilabel(app.TimelockPanel);
            app.PlotRangeEditFieldLabel.Text = 'Plot Range';
            app.PlotRangeEditField = uieditfield(app.TimelockPanel, 'text');
            app.PlotRangeEditField.Value = 'all';
            app.CovarianceCheckBox = uicheckbox(app.TimelockPanel);
            app.CovarianceCheckBox.Text = 'Covariance?';
            app.RemovemeanCheckBox = uicheckbox(app.TimelockPanel);
            app.RemovemeanCheckBox.Text = 'Remove mean?';
            app.AnalRangeEditFieldLabel = uilabel(app.TimelockPanel);
            app.AnalRangeEditFieldLabel.Text = 'Anal Range';
            app.AnalRangeEditField = uieditfield(app.TimelockPanel, 'text');
            app.AnalRangeEditField.Value = '0.2 1.5';
            app.doTimelockCheckBox = uicheckbox(app.TimelockPanel);
            app.doTimelockCheckBox.ValueChangedFcn = createCallbackFcn(app, @doTimelockCheckBoxValueChanged, true);
            app.doTimelockCheckBox.Text = '';
            app.doTimelockCheckBox.Value = true;
            app.ChannelsEditFieldLabel = uilabel(app.TimelockPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.TimelockPanel, 'text');
            app.ChannelsEditField.Value = '1 2';
            app.TimefrequencyPanel = uipanel(app.GridLayout);
            app.TimefrequencyPanel.Title = 'Time-frequency';
            app.FreqRangeEditFieldLabel = uilabel(app.TimefrequencyPanel);
            app.FreqRangeEditFieldLabel.Text = 'Freq Range';
            app.FreqRangeEditField = uieditfield(app.TimefrequencyPanel, 'text');
            app.FreqRangeEditField.Value = '1:1:25';
            app.TaperDropDownLabel = uilabel(app.TimefrequencyPanel);
            app.TaperDropDownLabel.Text = 'Taper';
            app.TaperDropDown = uidropdown(app.TimefrequencyPanel);
            app.TaperDropDown.Items = {'hanning'};
            app.TaperDropDown.Value = 'hanning';
            app.EditFieldLabel = uilabel(app.TimefrequencyPanel);
            app.EditFieldLabel.Text = ':';
            app.EditField = uieditfield(app.TimefrequencyPanel, 'text');
            app.EditField.Value = '-0.2 0';
            app.BaselineDropDownLabel = uilabel(app.TimefrequencyPanel);
            app.BaselineDropDownLabel.Text = 'Baseline';
            app.BaselineDropDown = uidropdown(app.TimefrequencyPanel);
            app.BaselineDropDown.Items = {'none', 'absolute', 'relative', 'db', 'zscore'};
            app.BaselineDropDown.Value = 'none';
            app.doTimeFreqCheckBox = uicheckbox(app.TimefrequencyPanel);
            app.doTimeFreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @doTimeFreqCheckBoxValueChanged, true);
            app.doTimeFreqCheckBox.Text = '';
            app.pDiodeLabel = uilabel(app.GridLayout);
            app.pDiodeLabel.Text = 'pDiode #';
            app.pDiodeEditField = uieditfield(app.GridLayout, 'text');
            app.pDiodeEditField.ValueChangedFcn = createCallbackFcn(app, @pDiodeEditFieldValueChanged, true);
            app.pDiodeEditField.Value = '11';
            app.CorrectLabel = uilabel(app.GridLayout);
            app.CorrectLabel.Text = 'Correct #';
            app.CorrectEditField = uieditfield(app.GridLayout, 'numeric');
            app.CorrectEditField.Limits = [0 1000];
            app.CorrectEditField.RoundFractionalValues = 'on';
            app.CorrectEditField.Value = 250;
            app.TriggersOKLampLabel = uilabel(app.GridLayout);
            app.TriggersOKLampLabel.Text = 'Triggers OK?';
            app.TriggersOKLamp = uilamp(app.GridLayout);
            app.versionLabel = uilabel(app.GridLayout);
            app.versionLabel.Text = '';
            app.FastFourierAnalysisPanel = uipanel(app.GridLayout);
            app.FastFourierAnalysisPanel.Title = 'Fast Fourier Analysis';
            app.AvgDropDownLabel = uilabel(app.FastFourierAnalysisPanel);
            app.AvgDropDownLabel.Text = 'Avg';
            app.AvgDropDown = uidropdown(app.FastFourierAnalysisPanel);
            app.AvgDropDown.Items = {'median', 'mean'};
            app.AvgDropDown.Value = 'median';
            app.ErrorDropDownLabel = uilabel(app.FastFourierAnalysisPanel);
            app.ErrorDropDownLabel.Text = 'Error';
            app.ErrorDropDown = uidropdown(app.FastFourierAnalysisPanel);
            app.ErrorDropDown.Items = {'SE', 'CIMEDIAN', 'CIMEAN', 'SD', '2SE', '2SD'};
            app.ErrorDropDown.Value = 'SE';
            app.pvalueEditFieldLabel = uilabel(app.FastFourierAnalysisPanel);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.FastFourierAnalysisPanel, 'numeric');
            app.pvalueEditField.Limits = [0 1];
            app.pvalueEditField.Tooltip = {'For CIMEDIAN and CIMEAN'};
            app.pvalueEditField.Value = 0.05;
            app.WindowedCheckBox = uicheckbox(app.FastFourierAnalysisPanel);
            app.WindowedCheckBox.Tooltip = {'FFT Hanning window?'};
            app.WindowedCheckBox.Text = 'Windowed?';
            app.CombinePlotsCheckBox = uicheckbox(app.FastFourierAnalysisPanel);
            app.CombinePlotsCheckBox.Tooltip = {'For the FFT frequencies, combine channels together for the plot (this is already done for the F1 calculation)'};
            app.CombinePlotsCheckBox.Text = 'Combine Plots?';
            app.CombinePlotsCheckBox.Value = true;
            app.LinearAxisCheckBox = uicheckbox(app.FastFourierAnalysisPanel);
            app.LinearAxisCheckBox.Tooltip = {'Use a simple linear X-axis where each value gets equal width'};
            app.LinearAxisCheckBox.Text = 'Linear Axis?';
            app.BlankCheckBox = uicheckbox(app.FastFourierAnalysisPanel);
            app.BlankCheckBox.Tooltip = {'Subtrack the control condition?'};
            app.BlankCheckBox.Text = '- Blank?';
            app.BlankCheckBox.Value = true;
            app.excludePointsEditField = uieditfield(app.FastFourierAnalysisPanel, 'text');
            app.excludePointsEditField.Tooltip = {'Which points to exclude from a linear regression?'};
            app.excludePointsEditField.Value = 'none';
            app.PointstoExcludefromFitLabel = uilabel(app.FastFourierAnalysisPanel);
            app.PointstoExcludefromFitLabel.Text = 'Points to Exclude from Fit?';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gear.png';
            app.Button.Tooltip = {'Reads the header information from the EDF file'};
            app.Button.Text = '';
            % Show the figure after all components are created
            app.VEPAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadEEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VEPAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VEPAnalysisUIFigure)
        end
    end
end